classdef gsea < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        scGEAppGSEAPrerankedUIFigure    matlab.ui.Figure
        SelectRankVaribleDropDownLabel  matlab.ui.control.Label
        SelectRankVaribleDropDown       matlab.ui.control.DropDown
        RunGSEAButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PrerankedGeneListTab            matlab.ui.container.Tab
        UITableGList                    matlab.ui.control.Table
        SaveTableAsButton               matlab.ui.control.Button
        AssignTableIntoWorkspaceButton_3  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button2                         matlab.ui.control.Button
        PositiveGSEAResultTab           matlab.ui.container.Tab
        UITablePRes                     matlab.ui.control.Table
        SaveTableAsButton_2             matlab.ui.control.Button
        AssignTableIntoWorkspaceButton  matlab.ui.control.Button
        NegativeGSEAResultTab           matlab.ui.container.Tab
        UITableNRes                     matlab.ui.control.Table
        SaveTableAsButton_3             matlab.ui.control.Button
        AssignTableIntoWorkspaceButton_2  matlab.ui.control.Button
        PlotResultsCheckBox             matlab.ui.control.CheckBox
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        LoadPrerankedGenesButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        Tgenelist
        Tpos % Positive Result Table
        Tneg % Negative Result Table
        Fig % Description
    end
    
    methods (Access = public)
        
        function load_data(app,T)
            app.Tgenelist=T;
            app.UITableGList.Data=app.Tgenelist;
            app.UITableGList.ColumnName=app.Tgenelist.Properties.VariableNames;
            app.SelectRankVaribleDropDown.Items=app.Tgenelist.Properties.VariableNames(2:end);
        end
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cdgea;
            addpath('thirdparty\GSEA\');
            app.UITablePRes.Tooltip={};
            app.UITableNRes.Tooltip={};

               app.Tgenelist=readtable('example_data\PreRanked_Genelist.txt');
                app.UITableGList.Data=app.Tgenelist;
                app.UITableGList.ColumnName=app.Tgenelist.Properties.VariableNames;
                app.SelectRankVaribleDropDown.Items=app.Tgenelist.Properties.VariableNames(2:end);            
                

%               screenSize = get(groot,'ScreenSize');
%               screenWidth = screenSize(3);
%               screenHeight = screenSize(4);
%               left = screenWidth*0.1;
%               bottom = screenHeight*0.1;
%               width = screenWidth*0.8;
%               height = screenHeight*0.8;
%               drawnow;
%               app.scGEAppGSEAPrerankedUIFigure.Position = [left bottom width height];                 
                
        end

        % Button pushed function: RunGSEAButton
        function RunGSEAButtonPushed(app, event)
            if strcmp(app.ReadyLampLabel.Text,'Done.')
                txt='Do you want to run the analysis again?';
            else
                txt='This analysis will take some time. Would you like to continue?';
            end
            answer = questdlg(txt, ...
        	'Running Time Warning', ...
        	'Yes','No','No');
        switch answer
            case 'No'
                return;
            case 'Yes'
                % OK, let's do it!
                app.ReadyLamp.Color='r';
                app.ReadyLamp.Enable=true;
                app.ReadyLampLabel.Visible=true;
                app.ReadyLampLabel.Text='Running...';
                pause(1)
        end
            % rmdir GSEA_plots s
            tic;
            t=app.UITableGList.Data;
            [~,idx]=ismember(app.SelectRankVaribleDropDown.Value,...
                app.SelectRankVaribleDropDown.Items);
            showplot=false;
            if app.PlotResultsCheckBox.Value, showplot=true; end
                 % fw = waitbar(0,'Please wait...'); 
                 pause(.5);               
                 glist=table2array(t(:,1));
                 grank=table2array(t(:,1+idx));
                 % waitbar(.33,fw,'Processing your data'); pause(.5);
                 [Tp,Tn]=run_gsea(grank,glist,showplot);
                 app.UITablePRes.Data=Tp;
                 app.UITablePRes.ColumnName=Tp.Properties.VariableNames;
                 app.UITableNRes.Data=Tn;
                 app.UITableNRes.ColumnName=Tn.Properties.VariableNames;                 
                 % waitbar(1,fw,'Finishing'); pause(1); close(fw);
                 app.Tpos=Tp;
                 app.Tneg=Tn;
                
                app.ReadyLamp.Color='g';
                app.ReadyLampLabel.Text='Done.';
                app.TabGroup.SelectedTab=app.PositiveGSEAResultTab;
                toc;
        end

        % Cell selection callback: UITablePRes
        function UITablePResCellSelection(app, event)
            indices = event.Indices;
            id=app.Tpos{indices(1),2};
            imfilename=sprintf('example_data/GS_4713.png');
            imtool(imfilename);
%             imfilename=sprintf('GSEA_plots/GS_%d.png',id);
%             if ~exist(imfilename,'file'), return; end
%             try
%                 imtool close all
%                 imtool(imfilename);
%             catch
%                 errordlg(sprintf('Error when showing %s',imfilename));          
%             end
        end

        % Cell selection callback: UITableNRes
        function UITableNResCellSelection(app, event)
            indices = event.Indices;
            id=app.Tneg{indices(1),2};
            imfilename=sprintf('GSEA_plots/GS_%d.png',id);
            if ~exist(imfilename,'file'), return; end
            try 
                imtool close all
                imtool(imfilename);
            catch
                errordlg(sprintf('Error when showing %s',imfilename));          
            end            
        end

        % Button pushed function: SaveTableAsButton_2
        function SaveTableAsButton_2Pushed(app, event)
            
            if ~isempty(app.Tpos)
            [file,path] = uiputfile('res_pos.txt');
            if file~=0
                writetable(app.Tpos,fullfile(path,file),'Delimiter','\t');
            end
            end
            
            
        end

        % Button pushed function: SaveTableAsButton_3
        function SaveTableAsButton_3Pushed(app, event)
            if ~isempty(app.Tneg)
            [file,path] = uiputfile('res_neg.txt');
            if file~=0
                writetable(app.Tneg,fullfile(path,file),'Delimiter','\t');
            end
            end            
        end

        % Button pushed function: SaveTableAsButton
        function SaveTableAsButtonPushed(app, event)
            t=app.UITableGList.Data;
            if ~isempty(t)
                [file,path] = uiputfile('ranked_genelist.txt');
                if file~=0
                    writetable(t,fullfile(path,file),'Delimiter','\t');
                end
            end  
        end

        % Button pushed function: AssignTableIntoWorkspaceButton_3
        function AssignTableIntoWorkspaceButton_3Pushed(app, event)
            t=app.UITableGList.Data;
            labels = {'Save Ranked Genelist to variable named:'}; 
                vars = {'T'}; 
                values = {t};
                export2wsdlg(labels,vars,values);
        end

        % Button pushed function: AssignTableIntoWorkspaceButton
        function AssignTableIntoWorkspaceButtonPushed(app, event)
            labels = {'Save Positive GSEA Result (Tpos) to variable named:'}; 
                vars = {'Tpos'}; 
                values = {app.Tpos};
                export2wsdlg(labels,vars,values);            
        end

        % Button pushed function: AssignTableIntoWorkspaceButton_2
        function AssignTableIntoWorkspaceButton_2Pushed(app, event)
            labels = {'Save Negative GSEA Result (Tneg) to variable named:'}; 
                vars = {'Tneg'};
                values = {app.Tneg};
                export2wsdlg(labels,vars,values);              
        end

        % Button pushed function: LoadPrerankedGenesButton
        function LoadPrerankedGenesButtonPushed(app, event)
            [file, path] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Preranked Gene List Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Gene List file');
        	if isequal(file,0), return; end
        	filename=fullfile(path,file);
                app.Tgenelist=readtable(filename);
                app.UITableGList.Data=app.Tgenelist;
                app.UITableGList.ColumnName=app.Tgenelist.Properties.VariableNames;
                app.SelectRankVaribleDropDown.Items=app.Tgenelist.Properties.VariableNames(2:end);
        end

        % Cell edit callback: UITablePRes
        function UITablePResCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITableGList
        function UITableGListCellSelection(app, event)
            indices = event.Indices;
            imfilename=sprintf('example_data/GS_4713.png');
            % imtool(imfilename);            
            im=imread(imfilename);
            if ~isempty(app.Fig)
                delete(app.Fig);
            end
            
            if isempty(app.Fig) || ~isvalid(app.Fig)
                app.Fig=uifigure;            
            end            
            
            a=uiaxes(app.Fig,'Position',[0 0 app.Fig.Position(3:4)]);
            imshow(im,'parent',a);
        
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % edit('demo_script1.mlx');
            web('oldfun\demo_script1.html')            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            % edit('demo_script1.mlx');
            web('oldfun\demo_script1.html')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.scGEAppGSEAPrerankedUIFigure = uifigure;
            app.scGEAppGSEAPrerankedUIFigure.Name = 'scGEApp - GSEA (Preranked)';
            app.SelectRankVaribleDropDownLabel = uilabel(app.scGEAppGSEAPrerankedUIFigure);
            app.SelectRankVaribleDropDownLabel.Text = 'Select Rank Varible';
            app.SelectRankVaribleDropDown = uidropdown(app.scGEAppGSEAPrerankedUIFigure);
            app.RunGSEAButton = uibutton(app.scGEAppGSEAPrerankedUIFigure, 'push');
            app.RunGSEAButton.ButtonPushedFcn = createCallbackFcn(app, @RunGSEAButtonPushed, true);
            app.RunGSEAButton.Text = 'Run GSEA...';
            app.TabGroup = uitabgroup(app.scGEAppGSEAPrerankedUIFigure);
            app.PrerankedGeneListTab = uitab(app.TabGroup);
            app.PrerankedGeneListTab.Title = 'Preranked Gene List';
            app.UITableGList = uitable(app.PrerankedGeneListTab);
            app.UITableGList.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableGList.RowName = {};
            app.UITableGList.CellSelectionCallback = createCallbackFcn(app, @UITableGListCellSelection, true);
            app.SaveTableAsButton = uibutton(app.PrerankedGeneListTab, 'push');
            app.SaveTableAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableAsButtonPushed, true);
            app.SaveTableAsButton.Text = 'Save Table As...';
            app.AssignTableIntoWorkspaceButton_3 = uibutton(app.PrerankedGeneListTab, 'push');
            app.AssignTableIntoWorkspaceButton_3.ButtonPushedFcn = createCallbackFcn(app, @AssignTableIntoWorkspaceButton_3Pushed, true);
            app.AssignTableIntoWorkspaceButton_3.Text = 'Assign Table Into Workspace...';
            app.Button = uibutton(app.PrerankedGeneListTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.PrerankedGeneListTab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.PositiveGSEAResultTab = uitab(app.TabGroup);
            app.PositiveGSEAResultTab.Title = 'Positive GSEA Result';
            app.UITablePRes = uitable(app.PositiveGSEAResultTab);
            app.UITablePRes.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITablePRes.RowName = {};
            app.UITablePRes.CellEditCallback = createCallbackFcn(app, @UITablePResCellEdit, true);
            app.UITablePRes.CellSelectionCallback = createCallbackFcn(app, @UITablePResCellSelection, true);
            app.UITablePRes.Tooltip = {'.'};
            app.SaveTableAsButton_2 = uibutton(app.PositiveGSEAResultTab, 'push');
            app.SaveTableAsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveTableAsButton_2Pushed, true);
            app.SaveTableAsButton_2.Text = 'Save Table As... ';
            app.AssignTableIntoWorkspaceButton = uibutton(app.PositiveGSEAResultTab, 'push');
            app.AssignTableIntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignTableIntoWorkspaceButtonPushed, true);
            app.AssignTableIntoWorkspaceButton.Text = 'Assign Table Into Workspace...';
            app.NegativeGSEAResultTab = uitab(app.TabGroup);
            app.NegativeGSEAResultTab.Title = 'Negative GSEA Result';
            app.UITableNRes = uitable(app.NegativeGSEAResultTab);
            app.UITableNRes.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableNRes.RowName = {};
            app.UITableNRes.CellSelectionCallback = createCallbackFcn(app, @UITableNResCellSelection, true);
            app.UITableNRes.Tooltip = {'.'};
            app.SaveTableAsButton_3 = uibutton(app.NegativeGSEAResultTab, 'push');
            app.SaveTableAsButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveTableAsButton_3Pushed, true);
            app.SaveTableAsButton_3.Text = 'Save Table As...';
            app.AssignTableIntoWorkspaceButton_2 = uibutton(app.NegativeGSEAResultTab, 'push');
            app.AssignTableIntoWorkspaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @AssignTableIntoWorkspaceButton_2Pushed, true);
            app.AssignTableIntoWorkspaceButton_2.Text = 'Assign Table Into Workspace...';
            app.PlotResultsCheckBox = uicheckbox(app.scGEAppGSEAPrerankedUIFigure);
            app.PlotResultsCheckBox.Text = 'Plot Results';
            app.PlotResultsCheckBox.Value = true;
            app.ReadyLampLabel = uilabel(app.scGEAppGSEAPrerankedUIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.scGEAppGSEAPrerankedUIFigure);
            app.ReadyLamp.Enable = 'off';
            app.LoadPrerankedGenesButton = uibutton(app.scGEAppGSEAPrerankedUIFigure, 'push');
            app.LoadPrerankedGenesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPrerankedGenesButtonPushed, true);
            app.LoadPrerankedGenesButton.Text = 'Load Preranked Genes...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gsea

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.scGEAppGSEAPrerankedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.scGEAppGSEAPrerankedUIFigure)
        end
    end
end