classdef scDETool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        scDEToolv10UIFigure        matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        LoadDataTab                matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        EditFieldLabel             matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        Button                     matlab.ui.control.Button
        Panel2                     matlab.ui.container.Panel
        EditField2Label            matlab.ui.control.Label
        EditField2                 matlab.ui.control.EditField
        Button2                    matlab.ui.control.Button
        Button5                    matlab.ui.control.Button
        NextButton_2               matlab.ui.control.Button
        Button4                    matlab.ui.control.Button
        NormalizationTab           matlab.ui.container.Tab
        NextButton                 matlab.ui.control.Button
        BackButton                 matlab.ui.control.Button
        logx1transformationButton  matlab.ui.control.StateButton
        UITable                    matlab.ui.control.Table
        UITable2                   matlab.ui.control.Table
        BatchCorrectionTab         matlab.ui.container.Tab
        NextButton_3               matlab.ui.control.Button
        BackButton_2               matlab.ui.control.Button
        Tab                        matlab.ui.container.Tab
    end

    
    properties (Access = private)
        X % Description
        Y
        genelistx
        genelisty
    end
    
    methods (Access = private)
        
        function results = update_expr_tables(app)
                                      if ~isempty(app.X) && ~isempty(app.genelistx)                
                t=table();
                t.genes=string(app.genelistx(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                app.UITable.Data=t;
                                      end
            
                            if ~isempty(app.Y) && ~isempty(app.genelisty)                
                t2=table();
                t2.genes=string(app.genelisty(1:50));
                t2=[t2,array2table(app.Y(1:50,1:20))];
                app.UITable2.Data=t2;   
                            end
                

        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        [app.X,app.genelistx]=sc_readfile('example_data/GSM3044891_GeneExp.UMIs.10X1.txt');
app.Y=app.X;
app.genelisty=app.genelistx;
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.NormalizationTab;
                if ~isempty(app.X) && ~isempty(app.genelistx)                
                t=table();
                t.genes=string(app.genelistx(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                app.UITable.Data=t;                
                end
                update_expr_tables(app);

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.BatchCorrectionTab;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.LoadDataTab;
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.NormalizationTab;
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            warndlg('Undefined action!')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % commandwindow;
            [filename, pathname] = uigetfile( ...
       {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
        '*.*',  'All Files (*.*)'}, ...
        'Pick a Exprssion Matrix file');
	if ~(filename), return; end
	filename=[pathname,filename];
app.EditField.Value=filename;

        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
                      [filename, pathname] = uigetfile( ...
       {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
        '*.*',  'All Files (*.*)'}, ...
        'Pick a Exprssion Matrix file');
	if ~(filename), return; end
	filename=[pathname,filename];
app.EditField2.Value=filename;            
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            [x,g]=sc_readfile(app.EditField.Value);
            app.X=x;
            app.genelistx=g;
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            if strcmp(app.EditField.Value, app.EditField2.Value)
                % errordlg('Same file');
            end
            [x,g]=sc_readfile(app.EditField2.Value);
            app.Y=x;
            app.genelisty=g;
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            
        end

        % Value changed function: logx1transformationButton
        function logx1transformationButtonValueChanged(app, event)
            
            value = app.logx1transformationButton.Value;
            if value
            app.X=log(1+app.X);
            app.Y=log(1+app.Y);
            update_expr_tables(app);
            else
                app.logx1transformationButton.Value=true;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.scDEToolv10UIFigure = uifigure;
            app.scDEToolv10UIFigure.Name = 'scDETool v1.0';
            app.TabGroup = uitabgroup(app.scDEToolv10UIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.Panel = uipanel(app.LoadDataTab);
            app.Panel.Title = 'Panel';
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Panel2 = uipanel(app.LoadDataTab);
            app.Panel2.Title = 'Panel2';
            app.EditField2Label = uilabel(app.Panel2);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.Panel2, 'text');
            app.Button2 = uibutton(app.Panel2, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.Button5 = uibutton(app.Panel2, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = 'Button5';
            app.NextButton_2 = uibutton(app.LoadDataTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next >>';
            app.Button4 = uibutton(app.LoadDataTab, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = 'Button4';
            app.NormalizationTab = uitab(app.TabGroup);
            app.NormalizationTab.Title = 'Normalization';
            app.NextButton = uibutton(app.NormalizationTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next >>';
            app.BackButton = uibutton(app.NormalizationTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '<< Back';
            app.logx1transformationButton = uibutton(app.NormalizationTab, 'state');
            app.logx1transformationButton.ValueChangedFcn = createCallbackFcn(app, @logx1transformationButtonValueChanged, true);
            app.logx1transformationButton.Text = 'log(x+1) transformation';
            app.UITable = uitable(app.NormalizationTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.NormalizationTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.BatchCorrectionTab = uitab(app.TabGroup);
            app.BatchCorrectionTab.Title = 'Batch Correction';
            app.NextButton_3 = uibutton(app.BatchCorrectionTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next >>';
            app.BackButton_2 = uibutton(app.BatchCorrectionTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = '<< Back';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = scDETool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.scDEToolv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.scDEToolv10UIFigure)
        end
    end
end