classdef scDETool2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        scDEToolv10UIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        Data1Panel                      matlab.ui.container.Panel
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        SelectFileButton                matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        Data2Panel                      matlab.ui.container.Panel
        FileNameEditField_2Label        matlab.ui.control.Label
        FileNameEditField_2             matlab.ui.control.EditField
        SelectFileButton_2              matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        FilterTab                       matlab.ui.container.Tab
        NextButton                      matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        FilterPanel                     matlab.ui.container.Panel
        MinimalofnonzerospergeneEditFieldLabel  matlab.ui.control.Label
        MinimalofnonzerospergeneEditField  matlab.ui.control.NumericEditField
        FilterGenesButton               matlab.ui.control.Button
        MinimalofnonzerospercellEditFieldLabel  matlab.ui.control.Label
        MinimalofnonzerospercellEditField  matlab.ui.control.NumericEditField
        FilterCellsButton               matlab.ui.control.Button
        GeneswithatleastEditFieldLabel  matlab.ui.control.Label
        GeneswithatleastEditField       matlab.ui.control.NumericEditField
        cellshavingmorethanEditFieldLabel  matlab.ui.control.Label
        cellshavingmorethanEditField    matlab.ui.control.NumericEditField
        readspercellLabel               matlab.ui.control.Label
        SelectGenesButton               matlab.ui.control.Button
        NormalizationTab                matlab.ui.container.Tab
        NextButton_3                    matlab.ui.control.Button
        BackButton_2                    matlab.ui.control.Button
        Method1Panel                    matlab.ui.container.Panel
        Logx1TransformationButton       matlab.ui.control.StateButton
        LibrarySizeNormalizationButton  matlab.ui.control.StateButton
        Method2Panel                    matlab.ui.container.Panel
        DESeqNormalizationButton        matlab.ui.control.StateButton
        BatchCorrectionTab              matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        AssignDataIntoWorkspaceButton   matlab.ui.control.Button
        SaveDataAsButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        X % Description
        Y
        genelistx
        genelisty
    end
    
    methods (Access = private)
        
        function update_expr_tables(app,tabid)
            if nargin<2, tabid=12; end
            if tabid==1 || tabid==12
            if ~isempty(app.X) && ~isempty(app.genelistx)                
                t=table();
                t.genes=string(app.genelistx(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                app.UITable.Data=t;
            end
            end
            if tabid==2 || tabid==12
            if ~isempty(app.Y) && ~isempty(app.genelisty)                
                t2=table();
                t2.genes=string(app.genelisty(1:50));
                t2=[t2,array2table(app.Y(1:50,1:20))];
                app.UITable2.Data=t2;
            end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.X,app.genelistx]=sc_readfile('example_data/GSM3044891_GeneExp.UMIs.10X1.txt');
            app.Y=app.X;
            app.genelisty=app.genelistx;
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
            if ~isempty(app.X) && ~isempty(app.genelistx)                
                t=table();
                t.genes=string(app.genelistx(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                app.UITable.Data=t;                
            end
                update_expr_tables(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.NormalizationTab;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.LoadDataTab;
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            % warndlg('Undefined action!')
            app.TabGroup.SelectedTab=app.BatchCorrectionTab;
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            % commandwindow;
            [filename, pathname] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Exprssion Matrix file');
        	if ~(filename), return; end
        	filename=[pathname,filename];
                app.FileNameEditField.Value=filename;
        end

        % Button pushed function: SelectFileButton_2
        function SelectFileButton_2Pushed(app, event)
            if ~isempty(app.X)
                      [filename, pathname] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Exprssion Matrix file');
        	if ~(filename), return; end
        	filename=[pathname,filename];
                app.FileNameEditField_2.Value=filename;
            else
                warndlg('Data 1 needs to be loaded first!');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if ~isempty(app.FileNameEditField.Value)
                [x,g]=sc_readfile(app.FileNameEditField.Value);
                app.X=x;
                app.genelistx=g;
                update_expr_tables(app,1);
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);                
            end
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            if strcmp(app.FileNameEditField.Value, app.FileNameEditField_2.Value)
                % errordlg('Same file');
            end
            if ~isempty(app.FileNameEditField_2.Value)
                [x,g]=sc_readfile(app.FileNameEditField_2.Value);
                app.Y=x;
                app.genelisty=g;
                update_expr_tables(app,2);
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            end
        end

        % Callback function
        function logx1transformationButtonValueChanged(app, event)
            update_expr_tables(app);            
            value = app.Logx1TransformationButton.Value;
            if value
                if ~isempty(app.X)
                    app.X=log(app.X+1);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=log(app.Y+1);
                    update_expr_tables(app,2);
                end

            else
                app.Logx1TransformationButton.Value=true;
            end
        end

        % Button pushed function: FilterGenesButton
        function FilterGenesButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)
                [app.X,app.genelistx]=sc_filtergenes(app.X,app.genelistx,...
                 app.MinimalofnonzerospergeneEditField.Value,true);
                 update_expr_tables(app,1);
                end
                if ~isempty(app.Y) && ~isempty(app.genelisty)    

                [app.Y,app.genelisty]=sc_filtergenes(app.Y,app.genelisty,...
                           app.MinimalofnonzerospergeneEditField.Value,true);
                 update_expr_tables(app,2);
                end                
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Button pushed function: FilterCellsButton
        function FilterCellsButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)                
                 [app.X]=sc_filtercells(app.X,app.MinimalofnonzerospercellEditField.Value,true);
                 update_expr_tables(app,1);
                            end
                if ~isempty(app.Y) && ~isempty(app.genelisty)                
                            
                 [app.Y]=sc_filtercells(app.Y,app.MinimalofnonzerospercellEditField.Value,true);
                 update_expr_tables(app,2);
                end
                 dbk=dbstack;
                 helpdlg([dbk(1).name '......done.']);            
        end

        % Value changed function: Logx1TransformationButton
        function Logx1TransformationButtonValueChanged2(app, event)
            value = app.Logx1TransformationButton.Value;
            if value
                if ~isempty(app.X)
                    app.X=log(1+app.X);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=log(1+app.Y);
                    update_expr_tables(app,2);
                end
%                app.Method2Panel.Visible=false;
                app.DESeqNormalizationButton.Enable=false;
                
            else
                app.Logx1TransformationButton.Value=true;
            end
        end

        % Value changed function: LibrarySizeNormalizationButton
        function LibrarySizeNormalizationButtonValueChanged(app, event)
            value = app.LibrarySizeNormalizationButton.Value;
            if value
                if ~isempty(app.X)
                 [app.X]=normalize_libsize(app.X);
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                 [app.Y]=normalize_libsize(app.Y);
                 update_expr_tables(app,2);
                 end
                app.DESeqNormalizationButton.Enable=false;
            else
                app.LibrarySizeNormalizationButton.Value=true;
            end
        end

        % Value changed function: DESeqNormalizationButton
        function DESeqNormalizationButtonValueChanged(app, event)
            value = app.DESeqNormalizationButton.Value;
            if value
                if ~isempty(app.X)
                 [app.X]=normalize_deseq(app.X);
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                 [app.Y]=normalize_deseq(app.Y);
                 update_expr_tables(app,2);
                 end
                app.LibrarySizeNormalizationButton.Enable=false;
                app.Logx1TransformationButton.Enable=false;
      
            else
                app.DESeqNormalizationButton.Value=true;
            end            
            
        end

        % Button pushed function: SelectGenesButton
        function SelectGenesButtonPushed(app, event)
            if ~isempty(app.X)
            [app.X,app.genelistx]=sc_selectgenes(app.X,app.genelistx,...
                app.cellshavingmorethanEditField.Value,...
                app.GeneswithatleastEditField.Value);
            update_expr_tables(app,1);
            end
            if ~isempty(app.Y)
            [app.Y,app.genelisty]=sc_selectgenes(app.Y,app.genelisty,...
                app.cellshavingmorethanEditField.Value,...
                app.GeneswithatleastEditField.Value);
            update_expr_tables(app,2);
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.scDEToolv10UIFigure = uifigure;
            app.scDEToolv10UIFigure.Name = 'scDETool v1.0';
            app.TabGroup = uitabgroup(app.scDEToolv10UIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.Data1Panel = uipanel(app.LoadDataTab);
            app.Data1Panel.Title = 'Data 1';
            app.FileNameEditFieldLabel = uilabel(app.Data1Panel);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.Data1Panel, 'text');
            app.SelectFileButton = uibutton(app.Data1Panel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File...';
            app.LoadButton = uibutton(app.Data1Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Data2Panel = uipanel(app.LoadDataTab);
            app.Data2Panel.Title = 'Data 2';
            app.FileNameEditField_2Label = uilabel(app.Data2Panel);
            app.FileNameEditField_2Label.Text = 'File Name:';
            app.FileNameEditField_2 = uieditfield(app.Data2Panel, 'text');
            app.SelectFileButton_2 = uibutton(app.Data2Panel, 'push');
            app.SelectFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_2Pushed, true);
            app.SelectFileButton_2.Text = 'Select File...';
            app.LoadButton_2 = uibutton(app.Data2Panel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.NextButton_2 = uibutton(app.LoadDataTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next >>';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.NextButton = uibutton(app.FilterTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next >>';
            app.BackButton = uibutton(app.FilterTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '<< Back';
            app.FilterPanel = uipanel(app.FilterTab);
            app.FilterPanel.Title = 'Filter';
            app.MinimalofnonzerospergeneEditFieldLabel = uilabel(app.FilterPanel);
            app.MinimalofnonzerospergeneEditFieldLabel.Text = 'Minimal # of nonzeros (per gene)';
            app.MinimalofnonzerospergeneEditField = uieditfield(app.FilterPanel, 'numeric');
            app.MinimalofnonzerospergeneEditField.Limits = [1 1000];
            app.MinimalofnonzerospergeneEditField.Value = 1;
            app.FilterGenesButton = uibutton(app.FilterPanel, 'push');
            app.FilterGenesButton.ButtonPushedFcn = createCallbackFcn(app, @FilterGenesButtonPushed, true);
            app.FilterGenesButton.Text = 'Filter Genes';
            app.MinimalofnonzerospercellEditFieldLabel = uilabel(app.FilterPanel);
            app.MinimalofnonzerospercellEditFieldLabel.Text = 'Minimal # of nonzeros (per cell)';
            app.MinimalofnonzerospercellEditField = uieditfield(app.FilterPanel, 'numeric');
            app.MinimalofnonzerospercellEditField.Limits = [1 1000];
            app.MinimalofnonzerospercellEditField.Value = 10;
            app.FilterCellsButton = uibutton(app.FilterPanel, 'push');
            app.FilterCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FilterCellsButtonPushed, true);
            app.FilterCellsButton.Text = 'Filter Cells';
            app.GeneswithatleastEditFieldLabel = uilabel(app.FilterTab);
            app.GeneswithatleastEditFieldLabel.Text = 'Genes with at least ';
            app.GeneswithatleastEditField = uieditfield(app.FilterTab, 'numeric');
            app.GeneswithatleastEditField.Limits = [1 Inf];
            app.GeneswithatleastEditField.Value = 2;
            app.cellshavingmorethanEditFieldLabel = uilabel(app.FilterTab);
            app.cellshavingmorethanEditFieldLabel.Text = 'cells having more than';
            app.cellshavingmorethanEditField = uieditfield(app.FilterTab, 'numeric');
            app.cellshavingmorethanEditField.Limits = [1 Inf];
            app.cellshavingmorethanEditField.Value = 4;
            app.readspercellLabel = uilabel(app.FilterTab);
            app.readspercellLabel.Text = 'reads per cell';
            app.SelectGenesButton = uibutton(app.FilterTab, 'push');
            app.SelectGenesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGenesButtonPushed, true);
            app.SelectGenesButton.Text = 'Select Genes';
            app.NormalizationTab = uitab(app.TabGroup);
            app.NormalizationTab.Title = 'Normalization';
            app.NextButton_3 = uibutton(app.NormalizationTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next >>';
            app.BackButton_2 = uibutton(app.NormalizationTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = '<< Back';
            app.Method1Panel = uipanel(app.NormalizationTab);
            app.Method1Panel.Title = 'Method 1';
            app.Logx1TransformationButton = uibutton(app.Method1Panel, 'state');
            app.Logx1TransformationButton.ValueChangedFcn = createCallbackFcn(app, @Logx1TransformationButtonValueChanged2, true);
            app.Logx1TransformationButton.Text = 'Log(x+1) Transformation';
            app.LibrarySizeNormalizationButton = uibutton(app.Method1Panel, 'state');
            app.LibrarySizeNormalizationButton.ValueChangedFcn = createCallbackFcn(app, @LibrarySizeNormalizationButtonValueChanged, true);
            app.LibrarySizeNormalizationButton.Text = 'Library Size Normalization';
            app.Method2Panel = uipanel(app.NormalizationTab);
            app.Method2Panel.Title = 'Method 2';
            app.DESeqNormalizationButton = uibutton(app.Method2Panel, 'state');
            app.DESeqNormalizationButton.ValueChangedFcn = createCallbackFcn(app, @DESeqNormalizationButtonValueChanged, true);
            app.DESeqNormalizationButton.Text = 'DESeq Normalization';
            app.BatchCorrectionTab = uitab(app.TabGroup);
            app.BatchCorrectionTab.Title = 'Batch Correction';
            app.UITable = uitable(app.scDEToolv10UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.scDEToolv10UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.AssignDataIntoWorkspaceButton = uibutton(app.scDEToolv10UIFigure, 'push');
            app.AssignDataIntoWorkspaceButton.Text = 'Assign Data Into Workspace...';
            app.SaveDataAsButton = uibutton(app.scDEToolv10UIFigure, 'push');
            app.SaveDataAsButton.Text = 'Save Data As...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = scDETool2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.scDEToolv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.scDEToolv10UIFigure)
        end
    end
end