classdef scDETool3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        scDEToolv10UIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        Data1Panel                      matlab.ui.container.Panel
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        SelectFileButton                matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        Data2Panel                      matlab.ui.container.Panel
        FileNameEditField_2Label        matlab.ui.control.Label
        FileNameEditField_2             matlab.ui.control.EditField
        SelectFileButton_2              matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        DataSourceButtonGroup           matlab.ui.container.ButtonGroup
        YourOwnButton                   matlab.ui.control.RadioButton
        Example1Button                  matlab.ui.control.RadioButton
        Example2Button                  matlab.ui.control.RadioButton
        FilterTab                       matlab.ui.container.Tab
        NextButton                      matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        FilterPanel                     matlab.ui.container.Panel
        MinimalofnonzerospergeneEditFieldLabel  matlab.ui.control.Label
        MinimalofnonzerospergeneEditField  matlab.ui.control.NumericEditField
        FilterGenesButton               matlab.ui.control.Button
        MinimalofnonzerospercellEditFieldLabel  matlab.ui.control.Label
        MinimalofnonzerospercellEditField  matlab.ui.control.NumericEditField
        FilterCellsButton               matlab.ui.control.Button
        GeneswithatleastEditFieldLabel  matlab.ui.control.Label
        GeneswithatleastEditField       matlab.ui.control.NumericEditField
        cellshavingmorethanEditFieldLabel  matlab.ui.control.Label
        cellshavingmorethanEditField    matlab.ui.control.NumericEditField
        readspercellLabel               matlab.ui.control.Label
        SelectGenesButton               matlab.ui.control.Button
        NormalizationTab                matlab.ui.container.Tab
        NextButton_3                    matlab.ui.control.Button
        BackButton_2                    matlab.ui.control.Button
        Method1Panel                    matlab.ui.container.Panel
        Logx1TransformationButton       matlab.ui.control.StateButton
        LibrarySizeNormalizationButton  matlab.ui.control.StateButton
        Method2Panel                    matlab.ui.container.Panel
        DESeqNormalizationButton        matlab.ui.control.StateButton
        BatchCorrectionTab              matlab.ui.container.Tab
        GeneIntersectionofData1andData2Button  matlab.ui.control.Button
        ImputationTab                   matlab.ui.container.Tab
        MAGICvanDijk2018ImputationButton  matlab.ui.control.StateButton
        FeatureSelectionTab             matlab.ui.container.Tab
        MeanCVDropout3DSplieFitButton   matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        DataSelectionButtonGroup        matlab.ui.container.ButtonGroup
        Data1Button                     matlab.ui.control.RadioButton
        Data2Button                     matlab.ui.control.RadioButton
        Data12Button                    matlab.ui.control.RadioButton
        CellsPanel                      matlab.ui.container.Panel
        PCAButton                       matlab.ui.control.Button
        tSNEButton                      matlab.ui.control.Button
        DiffusionMapButton              matlab.ui.control.Button
        AllGenesPanel                   matlab.ui.container.Panel
        MeanCVDropout3DScatterButton    matlab.ui.control.Button
        MeanDropout2DScatterButton      matlab.ui.control.Button
        MeanCV2DScatterButton           matlab.ui.control.Button
        MeanlogVarlog2DScatterButton    matlab.ui.control.Button
        FeaturedGenesPanel              matlab.ui.container.Panel
        ExpressionLandscape3DStemButton  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        AssignData1IntoWorkspaceButton  matlab.ui.control.Button
        SaveData1AsButton               matlab.ui.control.Button
        AssignData2IntoWorkspaceButton  matlab.ui.control.Button
        SaveData2AsButton               matlab.ui.control.Button
        Data1123genesx234cellsLabel     matlab.ui.control.Label
        ResultTab                       matlab.ui.container.Tab
        Tab                             matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        Node                            matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node5                           matlab.ui.container.TreeNode
        Node6                           matlab.ui.container.TreeNode
        Node_2                          matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
        SpinnerLabel                    matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        X % Description
        Y
        genelistx
        genelisty
    end
    
    methods (Access = private)
        
        function update_expr_tables(app,tabid)
            if nargin<2, tabid=12; end
            if tabid==1 || tabid==12
            if ~isempty(app.X) && ~isempty(app.genelistx)                
                t=table();
                t.genes=string(app.genelistx(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                app.UITable.Data=t;
                app.AssignData1IntoWorkspaceButton.Enable=true;
                app.SaveData1AsButton.Enable=true;
            end
            end
            if tabid==2 || tabid==12
            if ~isempty(app.Y) && ~isempty(app.genelisty)                
                t2=table();
                t2.genes=string(app.genelisty(1:50));
                t2=[t2,array2table(app.Y(1:50,1:20))];
                app.UITable2.Data=t2;
                app.AssignData2IntoWorkspaceButton.Enable=true;
                app.SaveData2AsButton.Enable=true;
            end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % [app.X,app.genelistx]=sc_readfile('example_data/GSM3044891_GeneExp.UMIs.10X1.txt');
            [app.X,app.genelistx]=sc_readfile('example_data/GSM3204305_P_N_Expr_999cells.csv');
            [app.Y,app.genelisty]=sc_readfile('example_data/GSM3204304_P_P_Expr_999cells.csv');            

        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
            if ~isempty(app.X) && ~isempty(app.genelistx)                
                t=table();
                t.genes=string(app.genelistx(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                app.UITable.Data=t;                
            end
                update_expr_tables(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.NormalizationTab;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.LoadDataTab;
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            % warndlg('Undefined action!')
            app.TabGroup.SelectedTab=app.BatchCorrectionTab;
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            % commandwindow;
            [filename, pathname] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Exprssion Matrix file');
        	if ~(filename), return; end
        	filename=[pathname,filename];
                app.FileNameEditField.Value=filename;
        end

        % Button pushed function: SelectFileButton_2
        function SelectFileButton_2Pushed(app, event)
            if ~isempty(app.X)
                      [filename, pathname] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Exprssion Matrix file');
        	if ~(filename), return; end
        	filename=[pathname,filename];
                app.FileNameEditField_2.Value=filename;
            else
                warndlg('Data 1 needs to be loaded first!');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if ~isempty(app.FileNameEditField.Value)
                [x,g]=sc_readfile(app.FileNameEditField.Value);
                app.X=x;
                app.genelistx=g;
                update_expr_tables(app,1);
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);                
            end
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            if strcmp(app.FileNameEditField.Value, app.FileNameEditField_2.Value)
                % errordlg('Same file');
            end
            if ~isempty(app.FileNameEditField_2.Value)
                [x,g]=sc_readfile(app.FileNameEditField_2.Value);
                app.Y=x;
                app.genelisty=g;
                update_expr_tables(app,2);
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            end
        end

        % Callback function
        function logx1transformationButtonValueChanged(app, event)
            update_expr_tables(app);            
            value = app.Logx1TransformationButton.Value;
            if value
                if ~isempty(app.X)
                    app.X=log(app.X+1);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=log(app.Y+1);
                    update_expr_tables(app,2);
                end

            else
                app.Logx1TransformationButton.Value=true;
            end
        end

        % Button pushed function: FilterGenesButton
        function FilterGenesButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)
                [app.X,app.genelistx]=sc_filtergenes(app.X,app.genelistx,...
                 app.MinimalofnonzerospergeneEditField.Value,true);
             
                 update_expr_tables(app,1);
                end
                if ~isempty(app.Y) && ~isempty(app.genelisty)    

                [app.Y,app.genelisty]=sc_filtergenes(app.Y,app.genelisty,...
                           app.MinimalofnonzerospergeneEditField.Value,true);
                 update_expr_tables(app,2);
                end                
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Button pushed function: FilterCellsButton
        function FilterCellsButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)                
                 [app.X]=sc_filtercells(app.X,app.MinimalofnonzerospercellEditField.Value,true);
                 update_expr_tables(app,1);
                            end
                if ~isempty(app.Y) && ~isempty(app.genelisty)                
                            
                 [app.Y]=sc_filtercells(app.Y,app.MinimalofnonzerospercellEditField.Value,true);
                 update_expr_tables(app,2);
                end
                 dbk=dbstack;
                 helpdlg([dbk(1).name '......done.']);            
        end

        % Value changed function: Logx1TransformationButton
        function Logx1TransformationButtonValueChanged2(app, event)
            value = app.Logx1TransformationButton.Value;
            if value
                if ~isempty(app.X)
                    app.X=log(1+app.X);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=log(1+app.Y);
                    update_expr_tables(app,2);
                end
%                app.Method2Panel.Visible=false;
                app.DESeqNormalizationButton.Enable=false;
                
            else
                app.Logx1TransformationButton.Value=true;
            end
        end

        % Value changed function: LibrarySizeNormalizationButton
        function LibrarySizeNormalizationButtonValueChanged(app, event)
            value = app.LibrarySizeNormalizationButton.Value;
            if value
                if ~isempty(app.X)
                 [app.X]=normalize_libsize(app.X);
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                 [app.Y]=normalize_libsize(app.Y);
                 update_expr_tables(app,2);
                 end
                app.DESeqNormalizationButton.Enable=false;
            else
                app.LibrarySizeNormalizationButton.Value=true;
            end
        end

        % Value changed function: DESeqNormalizationButton
        function DESeqNormalizationButtonValueChanged(app, event)
            value = app.DESeqNormalizationButton.Value;
            if value
                if ~isempty(app.X)
                 [app.X]=normalize_deseq(app.X);
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                 [app.Y]=normalize_deseq(app.Y);
                 update_expr_tables(app,2);
                 end
                app.LibrarySizeNormalizationButton.Enable=false;
                app.Logx1TransformationButton.Enable=false;
      
            else
                app.DESeqNormalizationButton.Value=true;
            end            
            
        end

        % Button pushed function: SelectGenesButton
        function SelectGenesButtonPushed(app, event)
            if ~isempty(app.X)
            [app.X,app.genelistx]=sc_selectgenes(app.X,app.genelistx,...
                app.cellshavingmorethanEditField.Value,...
                app.GeneswithatleastEditField.Value);
            update_expr_tables(app,1);
            end
            if ~isempty(app.Y)
            [app.Y,app.genelisty]=sc_selectgenes(app.Y,app.genelisty,...
                app.cellshavingmorethanEditField.Value,...
                app.GeneswithatleastEditField.Value);
            update_expr_tables(app,2);
            end            
        end

        % Button pushed function: AssignData1IntoWorkspaceButton
        function AssignData1IntoWorkspaceButtonPushed(app, event)
                labels = {'Save app.X to variable named:' ...
                      'Save app.GENELISTX to variable named:'}; 
                vars = {'X','genelistx'}; 
                values = {app.X,app.genelistx};
                export2wsdlg(labels,vars,values);
        end

        % Button pushed function: AssignData2IntoWorkspaceButton
        function AssignData2IntoWorkspaceButtonPushed(app, event)
                labels = {'Save app.Y to variable named:' ...
                      'Save app.GENELISTY to variable named:'}; 
                vars = {'Y','genelisty'}; 
                values = {app.Y,app.genelisty};
                export2wsdlg(labels,vars,values);            
        end

        % Button pushed function: SaveData1AsButton
        function SaveData1AsButtonPushed(app, event)
            
        end

        % Value changed function: MAGICvanDijk2018ImputationButton
        function MAGICvanDijk2018ImputationButtonValueChanged(app, event)
            value = app.MAGICvanDijk2018ImputationButton.Value;
            if value
                if ~isempty(app.X)
            f = waitbar(0,'Please wait...'); pause(.5)
            waitbar(.33,f,'Processing your data'); pause(.5);
            [app.X]=sc_impute_magic(app.X);
            waitbar(1,f,'Finishing'); pause(1); close(f);
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
            f = waitbar(0,'Please wait...'); pause(.5)
            waitbar(.33,f,'Processing your data'); pause(.5);
            [app.Y]=sc_impute_magic(app.Y);
            waitbar(1,f,'Finishing'); pause(1); close(f);
                 update_expr_tables(app,2);
                 end      
            else
                app.MAGICvanDijk2018ImputationButton.Value=true;
            end                   
            
        end

        % Button pushed function: 
        % GeneIntersectionofData1andData2Button
        function GeneIntersectionofData1andData2ButtonPushed(app, event)
            if ~isempty(app.X) && ~isempty(app.Y)
            [genelist,i,j]=intersect(app.genelistx,app.genelisty,'stable');
            app.genelistx=genelist;
            app.genelisty=genelist;
            app.X=app.X(i,:);
            app.Y=app.Y(j,:);
            update_expr_tables(app);
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            end
        end

        % Selection changed function: DataSourceButtonGroup
        function DataSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataSourceButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Your Own'
                    app.FileNameEditField.Value='';
                    app.FileNameEditField_2.Value='';
                case 'Example 1'
                    app.FileNameEditField.Value='example_data/GSM3204304_P_P_Expr_999cells.txt';
                case 'Example 2'
                    app.FileNameEditField.Value='example_data/GSM3204304_P_P_Expr_999cells.txt';
                    app.FileNameEditField_2.Value='example_data/GSM3204305_P_N_Expr_999cells.txt';
            end
        end

        % Button pushed function: tSNEButton
        function tSNEButtonPushed(app, event)
            f = waitbar(0,'Please wait...'); pause(.5);
            waitbar(.33,f,'Processing your data'); pause(.5);
            s=tsne(app.X');
            waitbar(1,f,'Finishing'); pause(1); close(f);
            fig = uifigure;
            ax = uiaxes(fig,'Position',[10 10 520 360]);     
            scatter(ax,s(:,1),s(:,2));
            xlabel(ax,'tSNP\_1');
            ylabel(ax,'tSNP\_2');
            title(ax,'tSNE (Perplexity = 30)');            
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
            f = waitbar(0,'Please wait...'); pause(.5)
            waitbar(.33,f,'Processing your data'); pause(.5);
            [~,s]=pca(app.X');
            waitbar(1,f,'Finishing'); pause(1); close(f);
            figure;
            scatter(s(:,1),s(:,2));
            box on
            xlabel('PC\_1');
            ylabel('PC\_2');            
        end

        % Button pushed function: MeanCVDropout3DScatterButton
        function MeanCVDropout3DScatterButtonPushed(app, event)
            figure;
            scatter3_mean_cv_dropr(app.X);            
        end

        % Button pushed function: MeanDropout2DScatterButton
        function MeanDropout2DScatterButtonPushed(app, event)
            figure;
            subplot(2,2,1)
            scatter_mean_vs_dropr(app.X,app.genelist,1);
            subplot(2,2,2)
            scatter_mean_vs_dropr(app.X,app.genelist,3);            
        end

        % Button pushed function: MeanCV2DScatterButton
        function MeanCV2DScatterButtonPushed(app, event)
                        figure;
            scatter_mean_vs_cv(app.X,app.genelist);
        end

        % Button pushed function: MeanlogVarlog2DScatterButton
        function MeanlogVarlog2DScatterButtonPushed(app, event)
            figure;
            scatter_meanlg_vs_varlg(app.X,app.genelist);            
        end

        % Button pushed function: ExpressionLandscape3DStemButton
        function ExpressionLandscape3DStemButtonPushed(app, event)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            c = uicontextmenu;
            m1 = uimenu(c,'Data1123genesx234cellsLabel','Show Data Info','Callback',@showdatainfo);
            m2 = uimenu(c,'Data1123genesx234cellsLabel','Show Gene Info','Callback',@showdatainfo);
            app.UITable.UIContextMenu=c;
            function showdatainfo(source,callbackdata)
                switch source.Data1123genesx234cellsLabel
                    case 'Show Data info'
                        disp('bbb')
                    case 'Show Gene info'
                        disp('aaa')
                end
            end           
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.scDEToolv10UIFigure = uifigure;
            app.scDEToolv10UIFigure.Name = 'scDETool v1.0';
            app.TabGroup = uitabgroup(app.scDEToolv10UIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.Data1Panel = uipanel(app.LoadDataTab);
            app.Data1Panel.Title = 'Data 1';
            app.FileNameEditFieldLabel = uilabel(app.Data1Panel);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.Data1Panel, 'text');
            app.SelectFileButton = uibutton(app.Data1Panel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File...';
            app.LoadButton = uibutton(app.Data1Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Data2Panel = uipanel(app.LoadDataTab);
            app.Data2Panel.Title = 'Data 2';
            app.FileNameEditField_2Label = uilabel(app.Data2Panel);
            app.FileNameEditField_2Label.Text = 'File Name:';
            app.FileNameEditField_2 = uieditfield(app.Data2Panel, 'text');
            app.SelectFileButton_2 = uibutton(app.Data2Panel, 'push');
            app.SelectFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_2Pushed, true);
            app.SelectFileButton_2.Text = 'Select File...';
            app.LoadButton_2 = uibutton(app.Data2Panel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.NextButton_2 = uibutton(app.LoadDataTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next >>';
            app.DataSourceButtonGroup = uibuttongroup(app.LoadDataTab);
            app.DataSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSourceButtonGroupSelectionChanged, true);
            app.DataSourceButtonGroup.Title = 'Data Source:';
            app.YourOwnButton = uiradiobutton(app.DataSourceButtonGroup);
            app.YourOwnButton.Text = 'Your Own';
            app.YourOwnButton.Value = true;
            app.Example1Button = uiradiobutton(app.DataSourceButtonGroup);
            app.Example1Button.Text = 'Example 1';
            app.Example2Button = uiradiobutton(app.DataSourceButtonGroup);
            app.Example2Button.Text = 'Example 2';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.NextButton = uibutton(app.FilterTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next >>';
            app.BackButton = uibutton(app.FilterTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '<< Back';
            app.FilterPanel = uipanel(app.FilterTab);
            app.FilterPanel.Title = 'Filter';
            app.MinimalofnonzerospergeneEditFieldLabel = uilabel(app.FilterPanel);
            app.MinimalofnonzerospergeneEditFieldLabel.Text = 'Minimal # of nonzeros (per gene)';
            app.MinimalofnonzerospergeneEditField = uieditfield(app.FilterPanel, 'numeric');
            app.MinimalofnonzerospergeneEditField.Limits = [1 1000];
            app.MinimalofnonzerospergeneEditField.Value = 1;
            app.FilterGenesButton = uibutton(app.FilterPanel, 'push');
            app.FilterGenesButton.ButtonPushedFcn = createCallbackFcn(app, @FilterGenesButtonPushed, true);
            app.FilterGenesButton.Text = 'Filter Genes';
            app.MinimalofnonzerospercellEditFieldLabel = uilabel(app.FilterPanel);
            app.MinimalofnonzerospercellEditFieldLabel.Text = 'Minimal # of nonzeros (per cell)';
            app.MinimalofnonzerospercellEditField = uieditfield(app.FilterPanel, 'numeric');
            app.MinimalofnonzerospercellEditField.Limits = [1 1000];
            app.MinimalofnonzerospercellEditField.Value = 10;
            app.FilterCellsButton = uibutton(app.FilterPanel, 'push');
            app.FilterCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FilterCellsButtonPushed, true);
            app.FilterCellsButton.Text = 'Filter Cells';
            app.GeneswithatleastEditFieldLabel = uilabel(app.FilterTab);
            app.GeneswithatleastEditFieldLabel.Text = 'Genes with at least ';
            app.GeneswithatleastEditField = uieditfield(app.FilterTab, 'numeric');
            app.GeneswithatleastEditField.Limits = [1 Inf];
            app.GeneswithatleastEditField.Value = 2;
            app.cellshavingmorethanEditFieldLabel = uilabel(app.FilterTab);
            app.cellshavingmorethanEditFieldLabel.Text = 'cells having more than';
            app.cellshavingmorethanEditField = uieditfield(app.FilterTab, 'numeric');
            app.cellshavingmorethanEditField.Limits = [1 Inf];
            app.cellshavingmorethanEditField.Value = 4;
            app.readspercellLabel = uilabel(app.FilterTab);
            app.readspercellLabel.Text = 'reads per cell';
            app.SelectGenesButton = uibutton(app.FilterTab, 'push');
            app.SelectGenesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGenesButtonPushed, true);
            app.SelectGenesButton.Text = 'Select Genes';
            app.NormalizationTab = uitab(app.TabGroup);
            app.NormalizationTab.Title = 'Normalization';
            app.NextButton_3 = uibutton(app.NormalizationTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next >>';
            app.BackButton_2 = uibutton(app.NormalizationTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = '<< Back';
            app.Method1Panel = uipanel(app.NormalizationTab);
            app.Method1Panel.Title = 'Method 1';
            app.Logx1TransformationButton = uibutton(app.Method1Panel, 'state');
            app.Logx1TransformationButton.ValueChangedFcn = createCallbackFcn(app, @Logx1TransformationButtonValueChanged2, true);
            app.Logx1TransformationButton.Text = 'Log(x+1) Transformation';
            app.LibrarySizeNormalizationButton = uibutton(app.Method1Panel, 'state');
            app.LibrarySizeNormalizationButton.ValueChangedFcn = createCallbackFcn(app, @LibrarySizeNormalizationButtonValueChanged, true);
            app.LibrarySizeNormalizationButton.Text = 'Library Size Normalization';
            app.Method2Panel = uipanel(app.NormalizationTab);
            app.Method2Panel.Title = 'Method 2';
            app.DESeqNormalizationButton = uibutton(app.Method2Panel, 'state');
            app.DESeqNormalizationButton.ValueChangedFcn = createCallbackFcn(app, @DESeqNormalizationButtonValueChanged, true);
            app.DESeqNormalizationButton.Text = 'DESeq Normalization';
            app.BatchCorrectionTab = uitab(app.TabGroup);
            app.BatchCorrectionTab.Title = 'Batch Correction';
            app.GeneIntersectionofData1andData2Button = uibutton(app.BatchCorrectionTab, 'push');
            app.GeneIntersectionofData1andData2Button.ButtonPushedFcn = createCallbackFcn(app, @GeneIntersectionofData1andData2ButtonPushed, true);
            app.GeneIntersectionofData1andData2Button.Text = 'Gene Intersection of Data 1 and Data 2';
            app.ImputationTab = uitab(app.TabGroup);
            app.ImputationTab.Title = 'Imputation';
            app.MAGICvanDijk2018ImputationButton = uibutton(app.ImputationTab, 'state');
            app.MAGICvanDijk2018ImputationButton.ValueChangedFcn = createCallbackFcn(app, @MAGICvanDijk2018ImputationButtonValueChanged, true);
            app.MAGICvanDijk2018ImputationButton.Text = 'MAGIC (van Dijk 2018) Imputation';
            app.FeatureSelectionTab = uitab(app.TabGroup);
            app.FeatureSelectionTab.Title = 'Feature Selection';
            app.MeanCVDropout3DSplieFitButton = uibutton(app.FeatureSelectionTab, 'push');
            app.MeanCVDropout3DSplieFitButton.Text = 'Mean-CV-Dropout 3D Splie Fit...';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.DataSelectionButtonGroup = uibuttongroup(app.VisualizationTab);
            app.DataSelectionButtonGroup.Title = 'Data Selection';
            app.Data1Button = uiradiobutton(app.DataSelectionButtonGroup);
            app.Data1Button.Text = 'Data 1';
            app.Data1Button.Value = true;
            app.Data2Button = uiradiobutton(app.DataSelectionButtonGroup);
            app.Data2Button.Text = 'Data 2';
            app.Data12Button = uiradiobutton(app.DataSelectionButtonGroup);
            app.Data12Button.Text = 'Data 1 & 2';
            app.CellsPanel = uipanel(app.VisualizationTab);
            app.CellsPanel.Title = 'Cells';
            app.PCAButton = uibutton(app.CellsPanel, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Text = 'PCA...';
            app.tSNEButton = uibutton(app.CellsPanel, 'push');
            app.tSNEButton.ButtonPushedFcn = createCallbackFcn(app, @tSNEButtonPushed, true);
            app.tSNEButton.Text = 't-SNE...';
            app.DiffusionMapButton = uibutton(app.CellsPanel, 'push');
            app.DiffusionMapButton.Text = 'Diffusion Map...';
            app.AllGenesPanel = uipanel(app.VisualizationTab);
            app.AllGenesPanel.Title = 'All Genes';
            app.MeanCVDropout3DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanCVDropout3DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCVDropout3DScatterButtonPushed, true);
            app.MeanCVDropout3DScatterButton.Text = 'Mean-CV-Dropout 3D Scatter...';
            app.MeanDropout2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanDropout2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanDropout2DScatterButtonPushed, true);
            app.MeanDropout2DScatterButton.Text = 'Mean-Dropout 2D Scatter...';
            app.MeanCV2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanCV2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCV2DScatterButtonPushed, true);
            app.MeanCV2DScatterButton.Text = 'Mean-CV 2D Scatter...';
            app.MeanlogVarlog2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanlogVarlog2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanlogVarlog2DScatterButtonPushed, true);
            app.MeanlogVarlog2DScatterButton.Text = 'Mean(log)-Var(log) 2D Scatter...';
            app.FeaturedGenesPanel = uipanel(app.VisualizationTab);
            app.FeaturedGenesPanel.Title = 'Featured Genes';
            app.ExpressionLandscape3DStemButton = uibutton(app.FeaturedGenesPanel, 'push');
            app.ExpressionLandscape3DStemButton.ButtonPushedFcn = createCallbackFcn(app, @ExpressionLandscape3DStemButtonPushed, true);
            app.ExpressionLandscape3DStemButton.Text = 'Expression Landscape (3D Stem)...';
            app.TabGroup2 = uitabgroup(app.scDEToolv10UIFigure);
            app.DataTab = uitab(app.TabGroup2);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable2 = uitable(app.DataTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.AssignData1IntoWorkspaceButton = uibutton(app.DataTab, 'push');
            app.AssignData1IntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignData1IntoWorkspaceButtonPushed, true);
            app.AssignData1IntoWorkspaceButton.Enable = 'off';
            app.AssignData1IntoWorkspaceButton.Text = 'Assign Data 1 Into Workspace...';
            app.SaveData1AsButton = uibutton(app.DataTab, 'push');
            app.SaveData1AsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData1AsButtonPushed, true);
            app.SaveData1AsButton.Enable = 'off';
            app.SaveData1AsButton.Text = 'Save Data 1 As...';
            app.AssignData2IntoWorkspaceButton = uibutton(app.DataTab, 'push');
            app.AssignData2IntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignData2IntoWorkspaceButtonPushed, true);
            app.AssignData2IntoWorkspaceButton.Enable = 'off';
            app.AssignData2IntoWorkspaceButton.Text = 'Assign Data 2 Into Workspace...';
            app.SaveData2AsButton = uibutton(app.DataTab, 'push');
            app.SaveData2AsButton.Enable = 'off';
            app.SaveData2AsButton.Text = 'Save Data 2 As...';
            app.Data1123genesx234cellsLabel = uilabel(app.DataTab);
            app.Data1123genesx234cellsLabel.Text = 'Data 1: 123 genes x 234 cells';
            app.ResultTab = uitab(app.TabGroup2);
            app.ResultTab.Title = 'Result';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.Tree = uitree(app.Tab);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node5 = uitreenode(app.Node);
            app.Node5.Text = 'Node5';
            app.Node6 = uitreenode(app.Node);
            app.Node6.Text = 'Node6';
            app.Node_2 = uitreenode(app.Node6);
            app.Node_2.Text = 'Node';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.SpinnerLabel = uilabel(app.Tab);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.Tab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = scDETool3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.scDEToolv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.scDEToolv10UIFigure)
        end
    end
end