classdef scDETool5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        scDEToolv10UIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        Data1Panel                      matlab.ui.container.Panel
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        SelectFileButton                matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        Data2Panel                      matlab.ui.container.Panel
        FileNameEditField_2Label        matlab.ui.control.Label
        FileNameEditField_2             matlab.ui.control.EditField
        SelectFileButton_2              matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        NextButton_LoadData             matlab.ui.control.Button
        DataSourceButtonGroup           matlab.ui.container.ButtonGroup
        YourOwnButton                   matlab.ui.control.RadioButton
        Example1DataButton              matlab.ui.control.RadioButton
        Example2DataButton              matlab.ui.control.RadioButton
        FilterTab                       matlab.ui.container.Tab
        NextButton_Filter               matlab.ui.control.Button
        BackButton_Filter               matlab.ui.control.Button
        FilterPanel                     matlab.ui.container.Panel
        MinimalofnonzerospergeneEditFieldLabel  matlab.ui.control.Label
        MinimalofnonzerospergeneEditField  matlab.ui.control.NumericEditField
        FilterGenesButton               matlab.ui.control.Button
        MinimalofnonzerospercellEditFieldLabel  matlab.ui.control.Label
        MinimalofnonzerospercellEditField  matlab.ui.control.NumericEditField
        FilterCellsButton               matlab.ui.control.Button
        GeneswithatleastEditFieldLabel  matlab.ui.control.Label
        GeneswithatleastEditField       matlab.ui.control.NumericEditField
        cellshavingmorethanEditFieldLabel  matlab.ui.control.Label
        cellshavingmorethanEditField    matlab.ui.control.NumericEditField
        readspercellLabel               matlab.ui.control.Label
        SelectGenesButton               matlab.ui.control.Button
        NormalizationTab                matlab.ui.container.Tab
        NextButton_3                    matlab.ui.control.Button
        BackButton_2                    matlab.ui.control.Button
        Method1Panel                    matlab.ui.container.Panel
        Logx1TransformationButton       matlab.ui.control.StateButton
        LibrarySizeNormalizationButton  matlab.ui.control.StateButton
        Method2Panel                    matlab.ui.container.Panel
        DESeqNormalizationButton        matlab.ui.control.StateButton
        BatchCorrectionTab              matlab.ui.container.Tab
        GeneIntersectionofData1andData2Button  matlab.ui.control.Button
        ComBatButton                    matlab.ui.control.StateButton
        BackButton_3                    matlab.ui.control.Button
        NextButton_4                    matlab.ui.control.Button
        DataSelectionButtonGroup_Bat    matlab.ui.container.ButtonGroup
        Data1Button_Bat                 matlab.ui.control.RadioButton
        Data2Button_Bat                 matlab.ui.control.RadioButton
        Data12Button_Bat                matlab.ui.control.RadioButton
        ImputationTab                   matlab.ui.container.Tab
        MAGICvanDijk2018ImputationButton  matlab.ui.control.StateButton
        BackButton_4                    matlab.ui.control.Button
        NextButton_5                    matlab.ui.control.Button
        DataSelectionButtonGroup_Imp    matlab.ui.container.ButtonGroup
        Data1Button_Imp                 matlab.ui.control.RadioButton
        Data2Button_Imp                 matlab.ui.control.RadioButton
        Data12Button_Imp                matlab.ui.control.RadioButton
        FeatureSelectionTab             matlab.ui.container.Tab
        MeanCVDropout3DSplieFitButton   matlab.ui.control.Button
        DataSelectionButtonGroup_Fea    matlab.ui.container.ButtonGroup
        Data1Button_Fea                 matlab.ui.control.RadioButton
        Data2Button_Fea                 matlab.ui.control.RadioButton
        Data12Button_Fea                matlab.ui.control.RadioButton
        ShowFitCurveCheckBox            matlab.ui.control.CheckBox
        BackButton_5                    matlab.ui.control.Button
        NextButton_6                    matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        DataSelectionButtonGroup_Vis    matlab.ui.container.ButtonGroup
        Data1Button_Vis                 matlab.ui.control.RadioButton
        Data2Button_Vis                 matlab.ui.control.RadioButton
        Data12Button_Vis                matlab.ui.control.RadioButton
        CellsPanel                      matlab.ui.container.Panel
        PCAButton                       matlab.ui.control.Button
        tSNEButton                      matlab.ui.control.Button
        DiffusionMapButton              matlab.ui.control.Button
        AllGenesPanel                   matlab.ui.container.Panel
        MeanCVDropout3DScatterButton    matlab.ui.control.Button
        MeanDropout2DScatterButton      matlab.ui.control.Button
        MeanCV2DScatterButton           matlab.ui.control.Button
        MeanlogVarlog2DScatterButton    matlab.ui.control.Button
        FeaturedGenesPanel              matlab.ui.container.Panel
        ExpressionLandscape3DStemButton  matlab.ui.control.Button
        BackButton_6                    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        UITableData1                    matlab.ui.control.Table
        UITableData2                    matlab.ui.control.Table
        AssignData1IntoWorkspaceButton  matlab.ui.control.Button
        SaveData1AsButton               matlab.ui.control.Button
        AssignData2IntoWorkspaceButton  matlab.ui.control.Button
        SaveData2AsButton               matlab.ui.control.Button
        ResultTab                       matlab.ui.container.Tab
        UITableRes                      matlab.ui.control.Table
        SaveTableAsButton               matlab.ui.control.Button
        AssignTableIntoWorkspaceButton  matlab.ui.control.Button
        RunGSEAPrerankedButton          matlab.ui.control.Button
        AboutTab                        matlab.ui.container.Tab
        ContributorsTextAreaLabel       matlab.ui.control.Label
        ContributorsTextArea            matlab.ui.control.TextArea
        AuthorLabel                     matlab.ui.control.Label
        AuthorTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        X % Description
        Y
        genelistx
        genelisty
        T % Description
    end
    
    methods (Access = private)
        
        function update_expr_tables(app,tabid)
            if nargin<2, tabid=12; end
            if tabid==1 || tabid==12
            if ~isempty(app.X) && ~isempty(app.genelistx)                
                t=table();
                t.genes=string(app.genelistx(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                %t.genes=string(app.genelistx);
                %t=[t,array2table(app.X)];                
                app.UITableData1.Data=t;
                app.AssignData1IntoWorkspaceButton.Enable=true;
                app.SaveData1AsButton.Enable=true;
            end
            end
            if tabid==2 || tabid==12
            if ~isempty(app.Y) && ~isempty(app.genelisty)                
                t2=table();
                t2.genes=string(app.genelisty(1:50));
                t2=[t2,array2table(app.Y(1:50,1:20))];
                app.UITableData2.Data=t2;
                app.AssignData2IntoWorkspaceButton.Enable=true;
                app.SaveData2AsButton.Enable=true;
            end
            end
            if ~isempty(app.T)
                    app.UITableRes.ColumnName=app.T.Properties.VariableNames;
                    app.UITableRes.Data=app.T;
                    app.TabGroup2.SelectedTab=app.ResultTab;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cdgea;
            % [app.X,app.genelistx]=sc_readfile('example_data/GSM3044891_GeneExp.UMIs.10X1.txt');
           % [app.X,app.genelistx]=sc_readfile('example_data/GSM3204304_P_P_Expr_999cells.csv');
           % [app.Y,app.genelisty]=sc_readfile('example_data/GSM3204305_P_N_Expr_999cells.csv');            

        end

        % Button pushed function: NextButton_LoadData
        function NextButton_LoadDataPushed(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
%             if ~isempty(app.X) && ~isempty(app.genelistx)                
%                 t=table();
%                 %t.genes=string(app.genelistx(1:50));
%                 %t=[t,array2table(app.X(1:50,1:20))];
%                 t.genes=string(app.genelistx);
%                 t=[t,array2table(app.X)];
%                 app.UITableData1.Data=t;                
%             end
                % update_expr_tables(app);
              TabGroupSelectionChanged(app,event);                 
        end

        % Button pushed function: NextButton_Filter
        function NextButton_FilterPushed(app, event)
            app.TabGroup.SelectedTab=app.NormalizationTab;
                    TabGroupSelectionChanged(app,event);             
        end

        % Button pushed function: BackButton_Filter
        function BackButton_FilterPushed(app, event)
            app.TabGroup.SelectedTab=app.LoadDataTab;
                    TabGroupSelectionChanged(app,event);             
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.FilterTab;
            TabGroupSelectionChanged(app,event);             
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            % warndlg('Undefined action!')
            app.TabGroup.SelectedTab=app.BatchCorrectionTab;
            TabGroupSelectionChanged(app,event);             
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            % commandwindow;
            [filename, pathname] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Exprssion Matrix file');
        	if ~(filename), return; end
        	filename=[pathname,filename];
                app.FileNameEditField.Value=filename;
        end

        % Button pushed function: SelectFileButton_2
        function SelectFileButton_2Pushed(app, event)
            if ~isempty(app.X)
                      [filename, pathname] = uigetfile( ...
               {'*.csv;*.tab;*.txt', 'Expression Matrix Files (*.csv, *.tab, *.txt)';
                '*.*',  'All Files (*.*)'}, ...
                'Pick a Exprssion Matrix file');
        	if ~(filename), return; end
        	filename=[pathname,filename];
                app.FileNameEditField_2.Value=filename;
            else
                warndlg('Data 1 needs to be loaded first!');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if ~isempty(app.FileNameEditField.Value)
                [x,g]=sc_readfile(app.FileNameEditField.Value);
                app.X=x;
                app.genelistx=g;
                app.SelectFileButton_2.Enable=true;
                app.LoadButton_2.Enable=true;
                update_expr_tables(app,1);
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);                
            end
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            if strcmp(app.FileNameEditField.Value, app.FileNameEditField_2.Value)
                % errordlg('Same file');
            end
            if ~isempty(app.FileNameEditField_2.Value)
                [x,g]=sc_readfile(app.FileNameEditField_2.Value);
                app.Y=x;
                app.genelisty=g;
                update_expr_tables(app,2);
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            end
        end

        % Callback function
        function logx1transformationButtonValueChanged(app, event)
            update_expr_tables(app);            
            value = app.Logx1TransformationButton.Value;
            if value
                if ~isempty(app.X)
                    app.X=log(app.X+1);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=log(app.Y+1);
                    update_expr_tables(app,2);
                end

            else
                app.Logx1TransformationButton.Value=true;
            end
        end

        % Button pushed function: FilterGenesButton
        function FilterGenesButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)
                [app.X,app.genelistx]=sc_filtergenes(app.X,app.genelistx,...
                 app.MinimalofnonzerospergeneEditField.Value,true);
                 update_expr_tables(app,1);
                end
                if ~isempty(app.Y) && ~isempty(app.genelisty)    

                [app.Y,app.genelisty]=sc_filtergenes(app.Y,app.genelisty,...
                           app.MinimalofnonzerospergeneEditField.Value,true);
                 update_expr_tables(app,2);
                end                
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Button pushed function: FilterCellsButton
        function FilterCellsButtonPushed(app, event)
                if ~isempty(app.X) && ~isempty(app.genelistx)                
                 [app.X]=sc_filtercells(app.X,app.MinimalofnonzerospercellEditField.Value,true);
                 update_expr_tables(app,1);
                            end
                if ~isempty(app.Y) && ~isempty(app.genelisty)                
                            
                 [app.Y]=sc_filtercells(app.Y,app.MinimalofnonzerospercellEditField.Value,true);
                 update_expr_tables(app,2);
                end
                 dbk=dbstack;
                 helpdlg([dbk(1).name '......done.']);            
        end

        % Value changed function: Logx1TransformationButton
        function Logx1TransformationButtonValueChanged2(app, event)
            value = app.Logx1TransformationButton.Value;
            if value
                if ~isempty(app.X)
                    app.X=log(1+app.X);
                    update_expr_tables(app,1);
                end
                if ~isempty(app.Y)
                    app.Y=log(1+app.Y);
                    update_expr_tables(app,2);
                end
%                app.Method2Panel.Visible=false;
                app.DESeqNormalizationButton.Enable=false;
                
            else
                app.Logx1TransformationButton.Value=true;
            end
        end

        % Value changed function: LibrarySizeNormalizationButton
        function LibrarySizeNormalizationButtonValueChanged(app, event)
            value = app.LibrarySizeNormalizationButton.Value;
            if value
                if ~isempty(app.X)
                 [app.X]=normalize_libsize(app.X);
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                 [app.Y]=normalize_libsize(app.Y);
                 update_expr_tables(app,2);
                 end
                app.DESeqNormalizationButton.Enable=false;
            else
                app.LibrarySizeNormalizationButton.Value=true;
            end
        end

        % Value changed function: DESeqNormalizationButton
        function DESeqNormalizationButtonValueChanged(app, event)
            value = app.DESeqNormalizationButton.Value;
            if value
                if ~isempty(app.X)
                 [app.X]=normalize_deseq(app.X);
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
                 [app.Y]=normalize_deseq(app.Y);
                 update_expr_tables(app,2);
                 end
                app.LibrarySizeNormalizationButton.Enable=false;
                app.Logx1TransformationButton.Enable=false;
      
            else
                app.DESeqNormalizationButton.Value=true;
            end            
            
        end

        % Button pushed function: SelectGenesButton
        function SelectGenesButtonPushed(app, event)
            if ~isempty(app.X)
            [app.X,app.genelistx]=sc_selectgenes(app.X,app.genelistx,...
                app.cellshavingmorethanEditField.Value,...
                app.GeneswithatleastEditField.Value);
            update_expr_tables(app,1);
            end
            if ~isempty(app.Y)
            [app.Y,app.genelisty]=sc_selectgenes(app.Y,app.genelisty,...
                app.cellshavingmorethanEditField.Value,...
                app.GeneswithatleastEditField.Value);
            update_expr_tables(app,2);
            end            
        end

        % Button pushed function: AssignData1IntoWorkspaceButton
        function AssignData1IntoWorkspaceButtonPushed(app, event)
                labels = {'Save app.X to variable named:' ...
                      'Save app.GENELISTX to variable named:'}; 
                vars = {'X','genelistx'}; 
                values = {app.X,app.genelistx};
                export2wsdlg(labels,vars,values);
        end

        % Button pushed function: AssignData2IntoWorkspaceButton
        function AssignData2IntoWorkspaceButtonPushed(app, event)
                labels = {'Save app.Y to variable named:' ...
                      'Save app.GENELISTY to variable named:'}; 
                vars = {'Y','genelisty'}; 
                values = {app.Y,app.genelisty};
                export2wsdlg(labels,vars,values);            
        end

        % Button pushed function: SaveData1AsButton
        function SaveData1AsButtonPushed(app, event)
            if ~isempty(app.X)
            t=table();
                t.genes=string(app.genelistx);
                t=[t,array2table(app.X)];            
            
            [file,path] = uiputfile('data_1.txt');
            writetable(t,fullfile(path,file),'Delimiter','\t');
            end            
        end

        % Value changed function: MAGICvanDijk2018ImputationButton
        function MAGICvanDijk2018ImputationButtonValueChanged(app, event)
            value = app.MAGICvanDijk2018ImputationButton.Value;
            if value
                if ~isempty(app.X)
            f = waitbar(0,'Please wait...'); pause(.5)
            waitbar(.33,f,'Processing Data 1'); pause(.5);
            [app.X]=sc_impute_magic(app.X);
            waitbar(1,f,'Finishing'); pause(1); close(f);
                 update_expr_tables(app,1);
                 end
                 if ~isempty(app.Y)
            f = waitbar(0,'Please wait...'); pause(.5)
            waitbar(.33,f,'Processing Data 2'); pause(.5);
            [app.Y]=sc_impute_magic(app.Y);
            waitbar(1,f,'Finishing'); pause(1); close(f);
                 update_expr_tables(app,2);
                 end      
            else
                app.MAGICvanDijk2018ImputationButton.Value=true;
            end                   
            
        end

        % Button pushed function: 
        % GeneIntersectionofData1andData2Button
        function GeneIntersectionofData1andData2ButtonPushed(app, event)
            if ~isempty(app.X) && ~isempty(app.Y)
            [genelist,i,j]=intersect(app.genelistx,app.genelisty,'stable');
            app.genelistx=genelist;
            app.genelisty=genelist;
            app.X=app.X(i,:);
            app.Y=app.Y(j,:);
            update_expr_tables(app);
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            end
        end

        % Selection changed function: DataSourceButtonGroup
        function DataSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataSourceButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Your Own'
                    app.LoadButton_2.Enable=false;
                    app.SelectFileButton_2.Enable=false;                    
                    app.FileNameEditField.Value='';
                    app.FileNameEditField_2.Value='';
                case 'Example 1 Data'
                    app.LoadButton_2.Enable=false;
                    app.SelectFileButton_2.Enable=false;                  
                    app.FileNameEditField.Value='example_data/GSM3044891_GeneExp.UMIs.10X1.txt';
                    app.FileNameEditField_2.Value='';                    
                case 'Example 2 Data'
                    app.LoadButton_2.Enable=true;
                    app.SelectFileButton_2.Enable=true;
                    app.FileNameEditField.Value='example_data/GSM3204304_P_P_Expr_999cells.csv';
                    app.FileNameEditField_2.Value='example_data/GSM3204305_P_N_Expr_999cells.csv';
            end
        end

        % Button pushed function: tSNEButton
        function tSNEButtonPushed(app, event)
    
            
            if app.Data1Button_Vis.Value
                f = waitbar(0,'Please wait...'); pause(.5);
                waitbar(.33,f,'Processing your data'); pause(.5);
                s=tsne(app.X');
                waitbar(1,f,'Finishing'); pause(1); close(f);
%                 fig = uifigure;
%                 ax = uiaxes(fig,'Position',[10 10 520 360]);     
%                 scatter(ax,s(:,1),s(:,2));
                figure; scatter(s(:,1),s(:,2));
                xlabel(ax,'tSNE\_1');
                ylabel(ax,'tSNE\_2');
                title(ax,'tSNE (Perplexity = 30)'); 
            elseif app.Data2Button_Vis.Value
                f = waitbar(0,'Please wait...'); pause(.5);
                waitbar(.33,f,'Processing your data'); pause(.5);
                s=tsne(app.Y');
%                 waitbar(1,f,'Finishing'); pause(1); close(f);
%                 fig = uifigure;
%                 ax = uiaxes(fig,'Position',[10 10 520 360]);     
%                 scatter(ax,s(:,1),s(:,2));
                figure; scatter(s(:,1),s(:,2));
                xlabel(ax,'tSNE\_1');
                ylabel(ax,'tSNE\_2');
                title(ax,'tSNE (Perplexity = 30)');              
            elseif app.Data12Button_Vis.Value
                if any(app.genelistx~=app.genelisty), return; end
                f = waitbar(0,'Please wait...'); pause(.5);
                waitbar(.33,f,'Processing your data'); pause(.5);
                data=[app.X app.Y];
                indx=[zeros(size(app.X,2),1);ones(size(app.Y,2),1)];                
                s=tsne(data');
                waitbar(1,f,'Finishing'); pause(1); close(f);
                figure; gscatter(s(:,1),s(:,2),indx); xlabel('tSNE\_1'); ylabel('tSNE\_2');legend({'Data 1','Data 2'});                
            end
            
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
            if app.Data1Button_Vis.Value
                %f = waitbar(0,'Please wait...'); pause(.5)
                %waitbar(.33,f,'Processing your data'); pause(.5);
                [~,s]=pca(app.X');
                %waitbar(1,f,'Finishing'); pause(1); close(f);               
                figure; scatter(s(:,1),s(:,2)); xlabel('PC\_1'); ylabel('PC\_2');
            elseif app.Data2Button_Vis.Value
                [~,s]=pca(app.Y');
                figure; scatter(s(:,1),s(:,2)); xlabel('PC\_1'); ylabel('PC\_2');                
            elseif app.Data12Button_Vis.Value
                if any(app.genelistx~=app.genelisty), return; end
                data=[app.X app.Y];
                indx=[zeros(size(app.X,2),1);ones(size(app.Y,2),1)];
                [~,s]=pca(data');
                figure; gscatter(s(:,1),s(:,2),indx); xlabel('PC\_1'); ylabel('PC\_2');legend({'Data 1','Data 2'});                
            end
            
        end

        % Button pushed function: MeanCVDropout3DScatterButton
        function MeanCVDropout3DScatterButtonPushed(app, event)
             if app.Data1Button_Vis.Value
                data=app.X; genelist=app.genelistx;
             elseif app.Data2Button_Vis.Value
                data=app.Y; genelist=app.genelistx;
             elseif app.Data12Button_Vis.Value
                warndlg('This function is for single data set only.');
                return;
             end
            figure;
            scatter3_mean_cv_dropr(data,true,true,genelist);            
        end

        % Button pushed function: MeanDropout2DScatterButton
        function MeanDropout2DScatterButtonPushed(app, event)
             if app.Data1Button_Vis.Value
                data=app.X; genelist=app.genelistx;
             elseif app.Data2Button_Vis.Value
                data=app.Y; genelist=app.genelistx;
             elseif app.Data12Button_Vis.Value
                warndlg('This function is for single data set only.');
                return;
             end       
            figure;
%             subplot(2,2,1)
%             scatter_mean_vs_dropr(app.X,app.genelistx,1);
%            subplot(2,2,2)
            scatter_mean_vs_dropr(data,genelist,3);            
        end

        % Button pushed function: MeanCV2DScatterButton
        function MeanCV2DScatterButtonPushed(app, event)
             if app.Data1Button_Vis.Value
                data=app.X; genelist=app.genelistx;
             elseif app.Data2Button_Vis.Value
                data=app.Y; genelist=app.genelistx;
             elseif app.Data12Button_Vis.Value
                warndlg('This function is for single data set only.');
                return;
             end
            figure;
            scatter_mean_vs_cv(data,genelist);
        end

        % Button pushed function: MeanlogVarlog2DScatterButton
        function MeanlogVarlog2DScatterButtonPushed(app, event)
             if app.Data1Button_Vis.Value
                data=app.X; genelist=app.genelistx;
             elseif app.Data2Button_Vis.Value
                data=app.Y; genelist=app.genelistx;
             elseif app.Data12Button_Vis.Value
                warndlg('This function is for single data set only.');
                return;
             end            
            figure;
            scatter_meanlg_vs_varlg(data,genelist);            
        end

        % Button pushed function: ExpressionLandscape3DStemButton
        function ExpressionLandscape3DStemButtonPushed(app, event)
            if ~isempty(app.T)
            answer = questdlg('Top 50 genes will be selected based on the value of last column of Feature table...', ...
                'Select Action', ...
               	'Yes','Cancel','Yes');
                switch answer
                    case 'Yes'
                        
                    case 'Cancel'
                        return;
                end            
            bigX=[]; bigY=[]; bigZ=[];
            app.T=sortrows(app.T,size(app.T,2),'descend');
            [~,idx]=ismember(app.T.genes,app.genelistx);
            for k=1:50
                i=idx(k);
                z=app.X(i,:);
                x=k*ones(size(z));
                y=1:length(z);
                bigX=[bigX;x];
                bigY=[bigY;y];
                bigZ=[bigZ;z];
            end
            figure;
            stem3(bigX,bigY,bigZ,'marker','none');
            view(60,35)
            xlabel('Genes')
            ylabel('Cells')
            zlabel('Expression')
            set(gca,'XTick',1:50)
            set(gca,'XTickLabel',app.T.genes(1:50))
        end            

        end

        % Button pushed function: MeanCVDropout3DSplieFitButton
        function MeanCVDropout3DSplieFitButtonPushed(app, event)
            % app.DataSelectionButtonGroup_Fea
            if app.Data1Button_Fea.Value
                x=app.X; g=app.genelistx;
                    app.T=sc_3dfeadev(x,g);
                    app.UITableRes.Data=app.T;
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    app.ShowFitCurveCheckBox.Value
                    if app.ShowFitCurveCheckBox.Value==1
                                figure;
                                scatter3_mean_cv_dropr(x,true,true,g);   
                    end               
                
                
            elseif app.Data2Button_Fea.Value
                x=app.Y; g=app.genelisty;
                    app.T=sc_3dfeadev(x,g);
                    app.UITableRes.Data=app.T;
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    app.ShowFitCurveCheckBox.Value
                    if app.ShowFitCurveCheckBox.Value==1
                                figure;
                                scatter3_mean_cv_dropr(x,true,true,g);   
                    end                 
            elseif app.Data12Button_Fea.Value
                x=app.X; g=app.genelistx;
                    T1=sc_3dfeadev(x,g,false);
                    %T1.Properties.VariableNames={'genes','lgu1','dropr1','lgcv1','d1'};
                    T1=T1(:,[1 end]);
                    T1.Properties.VariableNames={'genes','D1'};
                        if app.ShowFitCurveCheckBox.Value==1
                                figure;
                                subplot(2,2,1)
                                scatter3_mean_cv_dropr(x,true,true,g);
                                title('Data 1');
                        end                                   
                
                x=app.Y; g=app.genelisty;
                    T2=sc_3dfeadev(x,g,false);
                    %T1.Properties.VariableNames={'genes','lgu2','dropr2','lgcv2','d2'};
                    T2=T2(:,[1 end]);
                    T2.Properties.VariableNames={'genes','D2'};
                    if app.ShowFitCurveCheckBox.Value==1
                                % figure;
                                subplot(2,2,2)
                                scatter3_mean_cv_dropr(x,true,true,g);
                                title('Data 2');
                    end
                    
                    
                    T=join(T1,T2);
                    T.DD = T.D1-T.D2;
                    T=sortrows(T,4,'descend');
                    app.T=T;
                    app.UITableRes.ColumnName=T.Properties.VariableNames;
                    app.UITableRes.Data=app.T;
                    app.TabGroup2.SelectedTab=app.ResultTab;                
            end
            

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            update_expr_tables(app);
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case 'Feature Selection'
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    if ~isempty(app.X)
                        app.Data1Button_Fea.Enable=true;
                    else
                         app.Data1Button_Fea.Enable=false;
                    end
                    if ~isempty(app.Y)
                        app.Data2Button_Fea.Enable=true;
                    else
                         app.Data2Button_Fea.Enable=false;
                    end
                    if ~isempty(app.X) && ~isempty(app.Y)
                        app.Data12Button_Fea.Enable=true;
                    else
                         app.Data12Button_Fea.Enable=false;
                    end                     
                   
                case 'Visualization'
                    app.TabGroup2.SelectedTab=app.ResultTab;
                    if ~isempty(app.X)
                        app.Data1Button_Vis.Enable=true;
                    else
                         app.Data1Button_Vis.Enable=false;
                    end
                    if ~isempty(app.Y)
                        app.Data2Button_Vis.Enable=true;
                    else
                         app.Data2Button_Vis.Enable=false;
                    end
                    if ~isempty(app.X) && ~isempty(app.Y)
                        app.Data12Button_Vis.Enable=true;
                    else
                         app.Data12Button_Vis.Enable=false;
                    end                 
                case 'Imputation'
                    app.TabGroup2.SelectedTab=app.DataTab;
                    if ~isempty(app.X)
                        app.Data1Button_Imp.Enable=true;
                    else
                         app.Data1Button_Imp.Enable=false;
                    end
                    if ~isempty(app.Y)
                        app.Data2Button_Imp.Enable=true;
                    else
                         app.Data2Button_Imp.Enable=false;
                    end
                    if ~isempty(app.X) && ~isempty(app.Y)
                        app.Data12Button_Imp.Enable=true;
                    else
                         app.Data12Button_Imp.Enable=false;
                    end 
                    
                case 'Batch Correction'
                    if ~isempty(app.X) && ~isempty(app.Y)
                        app.Data12Button_Bat.Enable=true;
                        app.GeneIntersectionofData1andData2Button.Enable=true;
                        app.ComBatButton.Enable=true;                        
                    else
                        app.Data12Button_Bat.Enable=false;
                        app.GeneIntersectionofData1andData2Button.Enable=false;
                        app.ComBatButton.Enable=false;                        
                    end
                otherwise
                    app.TabGroup2.SelectedTab=app.DataTab;
            end
        end

        % Button pushed function: RunGSEAPrerankedButton
        function RunGSEAPrerankedButtonPushed(app, event)

                addpath('thirdparty\GSEA_package\');
                a=gsea;
                t=app.UITableRes.Data;               
                a.SelectRankVaribleDropDown.Items=t.Properties.VariableNames(2:end);
                a.UITableGList.Data=t;
                a.UITableGList.ColumnName=t.Properties.VariableNames;                
        end

        % Button pushed function: AssignTableIntoWorkspaceButton
        function AssignTableIntoWorkspaceButtonPushed(app, event)
                labels = {'Save app.T to variable named:'}; 
                vars = {'T'}; 
                values = {app.T};
                export2wsdlg(labels,vars,values);
        end

        % Value changed function: ComBatButton
        function ComBatButtonValueChanged(app, event)
            if ~isempty(app.X) && ~isempty(app.Y)
            value = app.ComBatButton.Value;
            if value

                    [app.X,app.Y]=run_combat(app.X,app.Y);
                    update_expr_tables(app);
                    dbk=dbstack; helpdlg([dbk(1).name '......done.']);                
            else
                app.ComBatButton.Value=true;
            end
            end
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
               app.TabGroup.SelectedTab=app.NormalizationTab;
                    TabGroupSelectionChanged(app,event);                
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
                    app.TabGroup.SelectedTab=app.ImputationTab;
                    TabGroupSelectionChanged(app,event);                   
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
                  app.TabGroup.SelectedTab=app.BatchCorrectionTab;
                    TabGroupSelectionChanged(app,event);                   
        end

        % Button pushed function: NextButton_5
        function NextButton_5Pushed(app, event)
                    app.TabGroup.SelectedTab=app.FeatureSelectionTab;
                    TabGroupSelectionChanged(app,event);                        
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
                    app.TabGroup.SelectedTab=app.ImputationTab;
                    TabGroupSelectionChanged(app,event);                   
        end

        % Button pushed function: NextButton_6
        function NextButton_6Pushed(app, event)
                    app.TabGroup.SelectedTab=app.VisualizationTab;
                    TabGroupSelectionChanged(app,event);                                      
        end

        % Button pushed function: BackButton_6
        function BackButton_6Pushed(app, event)
                 app.TabGroup.SelectedTab=app.FeatureSelectionTab;
                 TabGroupSelectionChanged(app,event);                  
        end

        % Button pushed function: SaveData2AsButton
        function SaveData2AsButtonPushed(app, event)
            if ~isempty(app.Y)
            t=table();
                t.genes=string(app.genelisty);
                t=[t,array2table(app.Y)];
            
            [file,path] = uiputfile('data_2.txt');
            writetable(t,fullfile(path,file),'Delimiter','\t');
            end                    
        end

        % Button pushed function: SaveTableAsButton
        function SaveTableAsButtonPushed(app, event)
            if ~isempty(app.T)
            [file,path] = uiputfile('gene_feature_table.txt');
            writetable(app.T,fullfile(path,file),'Delimiter','\t');
            end                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.scDEToolv10UIFigure = uifigure;
            app.scDEToolv10UIFigure.Name = 'scDETool v1.0';
            app.TabGroup = uitabgroup(app.scDEToolv10UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.Data1Panel = uipanel(app.LoadDataTab);
            app.Data1Panel.Title = 'Data 1';
            app.FileNameEditFieldLabel = uilabel(app.Data1Panel);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.Data1Panel, 'text');
            app.SelectFileButton = uibutton(app.Data1Panel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File...';
            app.LoadButton = uibutton(app.Data1Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Data2Panel = uipanel(app.LoadDataTab);
            app.Data2Panel.Title = 'Data 2';
            app.FileNameEditField_2Label = uilabel(app.Data2Panel);
            app.FileNameEditField_2Label.Text = 'File Name:';
            app.FileNameEditField_2 = uieditfield(app.Data2Panel, 'text');
            app.SelectFileButton_2 = uibutton(app.Data2Panel, 'push');
            app.SelectFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_2Pushed, true);
            app.SelectFileButton_2.Enable = 'off';
            app.SelectFileButton_2.Text = 'Select File...';
            app.LoadButton_2 = uibutton(app.Data2Panel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Enable = 'off';
            app.LoadButton_2.Text = 'Load';
            app.NextButton_LoadData = uibutton(app.LoadDataTab, 'push');
            app.NextButton_LoadData.ButtonPushedFcn = createCallbackFcn(app, @NextButton_LoadDataPushed, true);
            app.NextButton_LoadData.Text = 'Next >>';
            app.DataSourceButtonGroup = uibuttongroup(app.LoadDataTab);
            app.DataSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSourceButtonGroupSelectionChanged, true);
            app.DataSourceButtonGroup.Title = 'Data Source:';
            app.YourOwnButton = uiradiobutton(app.DataSourceButtonGroup);
            app.YourOwnButton.Text = 'Your Own';
            app.YourOwnButton.Value = true;
            app.Example1DataButton = uiradiobutton(app.DataSourceButtonGroup);
            app.Example1DataButton.Text = 'Example 1 Data';
            app.Example2DataButton = uiradiobutton(app.DataSourceButtonGroup);
            app.Example2DataButton.Text = 'Example 2 Data';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.NextButton_Filter = uibutton(app.FilterTab, 'push');
            app.NextButton_Filter.ButtonPushedFcn = createCallbackFcn(app, @NextButton_FilterPushed, true);
            app.NextButton_Filter.Text = 'Next >>';
            app.BackButton_Filter = uibutton(app.FilterTab, 'push');
            app.BackButton_Filter.ButtonPushedFcn = createCallbackFcn(app, @BackButton_FilterPushed, true);
            app.BackButton_Filter.Text = '<< Back';
            app.FilterPanel = uipanel(app.FilterTab);
            app.FilterPanel.Title = 'Filter';
            app.MinimalofnonzerospergeneEditFieldLabel = uilabel(app.FilterPanel);
            app.MinimalofnonzerospergeneEditFieldLabel.Text = 'Minimal # of nonzeros (per gene)';
            app.MinimalofnonzerospergeneEditField = uieditfield(app.FilterPanel, 'numeric');
            app.MinimalofnonzerospergeneEditField.Limits = [1 1000];
            app.MinimalofnonzerospergeneEditField.Value = 1;
            app.FilterGenesButton = uibutton(app.FilterPanel, 'push');
            app.FilterGenesButton.ButtonPushedFcn = createCallbackFcn(app, @FilterGenesButtonPushed, true);
            app.FilterGenesButton.Text = 'Filter Genes';
            app.MinimalofnonzerospercellEditFieldLabel = uilabel(app.FilterPanel);
            app.MinimalofnonzerospercellEditFieldLabel.Text = 'Minimal # of nonzeros (per cell)';
            app.MinimalofnonzerospercellEditField = uieditfield(app.FilterPanel, 'numeric');
            app.MinimalofnonzerospercellEditField.Limits = [1 1000];
            app.MinimalofnonzerospercellEditField.Value = 10;
            app.FilterCellsButton = uibutton(app.FilterPanel, 'push');
            app.FilterCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FilterCellsButtonPushed, true);
            app.FilterCellsButton.Text = 'Filter Cells';
            app.GeneswithatleastEditFieldLabel = uilabel(app.FilterTab);
            app.GeneswithatleastEditFieldLabel.Text = 'Genes with at least ';
            app.GeneswithatleastEditField = uieditfield(app.FilterTab, 'numeric');
            app.GeneswithatleastEditField.Limits = [1 Inf];
            app.GeneswithatleastEditField.Value = 2;
            app.cellshavingmorethanEditFieldLabel = uilabel(app.FilterTab);
            app.cellshavingmorethanEditFieldLabel.Text = 'cells having more than';
            app.cellshavingmorethanEditField = uieditfield(app.FilterTab, 'numeric');
            app.cellshavingmorethanEditField.Limits = [1 Inf];
            app.cellshavingmorethanEditField.Value = 4;
            app.readspercellLabel = uilabel(app.FilterTab);
            app.readspercellLabel.Text = 'reads per cell';
            app.SelectGenesButton = uibutton(app.FilterTab, 'push');
            app.SelectGenesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGenesButtonPushed, true);
            app.SelectGenesButton.Text = 'Select Genes';
            app.NormalizationTab = uitab(app.TabGroup);
            app.NormalizationTab.Title = 'Normalization';
            app.NextButton_3 = uibutton(app.NormalizationTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next >>';
            app.BackButton_2 = uibutton(app.NormalizationTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = '<< Back';
            app.Method1Panel = uipanel(app.NormalizationTab);
            app.Method1Panel.Title = 'Method 1';
            app.Logx1TransformationButton = uibutton(app.Method1Panel, 'state');
            app.Logx1TransformationButton.ValueChangedFcn = createCallbackFcn(app, @Logx1TransformationButtonValueChanged2, true);
            app.Logx1TransformationButton.Text = 'Log(x+1) Transformation';
            app.LibrarySizeNormalizationButton = uibutton(app.Method1Panel, 'state');
            app.LibrarySizeNormalizationButton.ValueChangedFcn = createCallbackFcn(app, @LibrarySizeNormalizationButtonValueChanged, true);
            app.LibrarySizeNormalizationButton.Text = 'Library Size Normalization';
            app.Method2Panel = uipanel(app.NormalizationTab);
            app.Method2Panel.Title = 'Method 2';
            app.DESeqNormalizationButton = uibutton(app.Method2Panel, 'state');
            app.DESeqNormalizationButton.ValueChangedFcn = createCallbackFcn(app, @DESeqNormalizationButtonValueChanged, true);
            app.DESeqNormalizationButton.Text = 'DESeq Normalization';
            app.BatchCorrectionTab = uitab(app.TabGroup);
            app.BatchCorrectionTab.Title = 'Batch Correction';
            app.GeneIntersectionofData1andData2Button = uibutton(app.BatchCorrectionTab, 'push');
            app.GeneIntersectionofData1andData2Button.ButtonPushedFcn = createCallbackFcn(app, @GeneIntersectionofData1andData2ButtonPushed, true);
            app.GeneIntersectionofData1andData2Button.Text = 'Gene Intersection of Data 1 and Data 2';
            app.ComBatButton = uibutton(app.BatchCorrectionTab, 'state');
            app.ComBatButton.ValueChangedFcn = createCallbackFcn(app, @ComBatButtonValueChanged, true);
            app.ComBatButton.Text = 'ComBat';
            app.BackButton_3 = uibutton(app.BatchCorrectionTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = '<< Back';
            app.NextButton_4 = uibutton(app.BatchCorrectionTab, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Text = 'Next >>';
            app.DataSelectionButtonGroup_Bat = uibuttongroup(app.BatchCorrectionTab);
            app.DataSelectionButtonGroup_Bat.Title = 'Data Selection';
            app.Data1Button_Bat = uiradiobutton(app.DataSelectionButtonGroup_Bat);
            app.Data1Button_Bat.Enable = 'off';
            app.Data1Button_Bat.Text = 'Data 1';
            app.Data2Button_Bat = uiradiobutton(app.DataSelectionButtonGroup_Bat);
            app.Data2Button_Bat.Enable = 'off';
            app.Data2Button_Bat.Text = 'Data 2';
            app.Data12Button_Bat = uiradiobutton(app.DataSelectionButtonGroup_Bat);
            app.Data12Button_Bat.Text = 'Data 1 & 2';
            app.Data12Button_Bat.Value = true;
            app.ImputationTab = uitab(app.TabGroup);
            app.ImputationTab.Title = 'Imputation';
            app.MAGICvanDijk2018ImputationButton = uibutton(app.ImputationTab, 'state');
            app.MAGICvanDijk2018ImputationButton.ValueChangedFcn = createCallbackFcn(app, @MAGICvanDijk2018ImputationButtonValueChanged, true);
            app.MAGICvanDijk2018ImputationButton.Text = 'MAGIC (van Dijk 2018) Imputation';
            app.BackButton_4 = uibutton(app.ImputationTab, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = '<< Back';
            app.NextButton_5 = uibutton(app.ImputationTab, 'push');
            app.NextButton_5.ButtonPushedFcn = createCallbackFcn(app, @NextButton_5Pushed, true);
            app.NextButton_5.Text = 'Next >>';
            app.DataSelectionButtonGroup_Imp = uibuttongroup(app.ImputationTab);
            app.DataSelectionButtonGroup_Imp.Title = 'Data Selection';
            app.Data1Button_Imp = uiradiobutton(app.DataSelectionButtonGroup_Imp);
            app.Data1Button_Imp.Text = 'Data 1';
            app.Data1Button_Imp.Value = true;
            app.Data2Button_Imp = uiradiobutton(app.DataSelectionButtonGroup_Imp);
            app.Data2Button_Imp.Text = 'Data 2';
            app.Data12Button_Imp = uiradiobutton(app.DataSelectionButtonGroup_Imp);
            app.Data12Button_Imp.Text = 'Data 1 & 2 ';
            app.FeatureSelectionTab = uitab(app.TabGroup);
            app.FeatureSelectionTab.Title = 'Feature Selection';
            app.MeanCVDropout3DSplieFitButton = uibutton(app.FeatureSelectionTab, 'push');
            app.MeanCVDropout3DSplieFitButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCVDropout3DSplieFitButtonPushed, true);
            app.MeanCVDropout3DSplieFitButton.Text = 'Mean-CV-Dropout 3D Splie Fit...';
            app.DataSelectionButtonGroup_Fea = uibuttongroup(app.FeatureSelectionTab);
            app.DataSelectionButtonGroup_Fea.Title = 'Data Selection';
            app.Data1Button_Fea = uiradiobutton(app.DataSelectionButtonGroup_Fea);
            app.Data1Button_Fea.Text = 'Data 1';
            app.Data1Button_Fea.Value = true;
            app.Data2Button_Fea = uiradiobutton(app.DataSelectionButtonGroup_Fea);
            app.Data2Button_Fea.Text = 'Data 2';
            app.Data12Button_Fea = uiradiobutton(app.DataSelectionButtonGroup_Fea);
            app.Data12Button_Fea.Text = 'Data 1 & 2 ';
            app.ShowFitCurveCheckBox = uicheckbox(app.FeatureSelectionTab);
            app.ShowFitCurveCheckBox.Text = 'Show Fit Curve';
            app.BackButton_5 = uibutton(app.FeatureSelectionTab, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = '<< Back';
            app.NextButton_6 = uibutton(app.FeatureSelectionTab, 'push');
            app.NextButton_6.ButtonPushedFcn = createCallbackFcn(app, @NextButton_6Pushed, true);
            app.NextButton_6.Text = 'Next >>';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.DataSelectionButtonGroup_Vis = uibuttongroup(app.VisualizationTab);
            app.DataSelectionButtonGroup_Vis.Title = 'Data Selection';
            app.Data1Button_Vis = uiradiobutton(app.DataSelectionButtonGroup_Vis);
            app.Data1Button_Vis.Text = 'Data 1';
            app.Data1Button_Vis.Value = true;
            app.Data2Button_Vis = uiradiobutton(app.DataSelectionButtonGroup_Vis);
            app.Data2Button_Vis.Text = 'Data 2';
            app.Data12Button_Vis = uiradiobutton(app.DataSelectionButtonGroup_Vis);
            app.Data12Button_Vis.Text = 'Data 1 & 2';
            app.CellsPanel = uipanel(app.VisualizationTab);
            app.CellsPanel.Title = 'Cells';
            app.PCAButton = uibutton(app.CellsPanel, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Text = 'PCA...';
            app.tSNEButton = uibutton(app.CellsPanel, 'push');
            app.tSNEButton.ButtonPushedFcn = createCallbackFcn(app, @tSNEButtonPushed, true);
            app.tSNEButton.Text = 't-SNE...';
            app.DiffusionMapButton = uibutton(app.CellsPanel, 'push');
            app.DiffusionMapButton.Text = 'Diffusion Map...';
            app.AllGenesPanel = uipanel(app.VisualizationTab);
            app.AllGenesPanel.Title = 'All Genes';
            app.MeanCVDropout3DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanCVDropout3DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCVDropout3DScatterButtonPushed, true);
            app.MeanCVDropout3DScatterButton.Text = 'Mean-CV-Dropout 3D Scatter...';
            app.MeanDropout2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanDropout2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanDropout2DScatterButtonPushed, true);
            app.MeanDropout2DScatterButton.Text = 'Mean-Dropout 2D Scatter...';
            app.MeanCV2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanCV2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanCV2DScatterButtonPushed, true);
            app.MeanCV2DScatterButton.Text = 'Mean-CV 2D Scatter...';
            app.MeanlogVarlog2DScatterButton = uibutton(app.AllGenesPanel, 'push');
            app.MeanlogVarlog2DScatterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanlogVarlog2DScatterButtonPushed, true);
            app.MeanlogVarlog2DScatterButton.Text = 'Mean(log)-Var(log) 2D Scatter...';
            app.FeaturedGenesPanel = uipanel(app.VisualizationTab);
            app.FeaturedGenesPanel.Title = 'Featured Genes';
            app.ExpressionLandscape3DStemButton = uibutton(app.FeaturedGenesPanel, 'push');
            app.ExpressionLandscape3DStemButton.ButtonPushedFcn = createCallbackFcn(app, @ExpressionLandscape3DStemButtonPushed, true);
            app.ExpressionLandscape3DStemButton.Text = 'Expression Landscape (3D Stem)...';
            app.BackButton_6 = uibutton(app.VisualizationTab, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButton_6Pushed, true);
            app.BackButton_6.Text = '<< Back';
            app.TabGroup2 = uitabgroup(app.scDEToolv10UIFigure);
            app.DataTab = uitab(app.TabGroup2);
            app.DataTab.Title = 'Data';
            app.UITableData1 = uitable(app.DataTab);
            app.UITableData1.ColumnName = {'Genes'; 'Cell 1'; 'Cell 2'; 'Cell 3'};
            app.UITableData1.RowName = {};
            app.UITableData2 = uitable(app.DataTab);
            app.UITableData2.ColumnName = {'Genes'; 'Cell 1'; 'Cell 2'; 'Cell 3'};
            app.UITableData2.RowName = {};
            app.AssignData1IntoWorkspaceButton = uibutton(app.DataTab, 'push');
            app.AssignData1IntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignData1IntoWorkspaceButtonPushed, true);
            app.AssignData1IntoWorkspaceButton.Enable = 'off';
            app.AssignData1IntoWorkspaceButton.Text = 'Assign Data 1 Into Workspace...';
            app.SaveData1AsButton = uibutton(app.DataTab, 'push');
            app.SaveData1AsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData1AsButtonPushed, true);
            app.SaveData1AsButton.Enable = 'off';
            app.SaveData1AsButton.Text = 'Save Data 1 As...';
            app.AssignData2IntoWorkspaceButton = uibutton(app.DataTab, 'push');
            app.AssignData2IntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignData2IntoWorkspaceButtonPushed, true);
            app.AssignData2IntoWorkspaceButton.Enable = 'off';
            app.AssignData2IntoWorkspaceButton.Text = 'Assign Data 2 Into Workspace...';
            app.SaveData2AsButton = uibutton(app.DataTab, 'push');
            app.SaveData2AsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData2AsButtonPushed, true);
            app.SaveData2AsButton.Enable = 'off';
            app.SaveData2AsButton.Text = 'Save Data 2 As...';
            app.ResultTab = uitab(app.TabGroup2);
            app.ResultTab.Title = 'Result';
            app.UITableRes = uitable(app.ResultTab);
            app.UITableRes.ColumnName = {'Genes'; 'Log10_Mean'; 'Dropout_Rate'; 'Log10_CV'; 'Deviation_3D'};
            app.UITableRes.RowName = {};
            app.SaveTableAsButton = uibutton(app.ResultTab, 'push');
            app.SaveTableAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableAsButtonPushed, true);
            app.SaveTableAsButton.Text = 'Save Table As...';
            app.AssignTableIntoWorkspaceButton = uibutton(app.ResultTab, 'push');
            app.AssignTableIntoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AssignTableIntoWorkspaceButtonPushed, true);
            app.AssignTableIntoWorkspaceButton.Text = 'Assign Table Into Workspace...';
            app.RunGSEAPrerankedButton = uibutton(app.ResultTab, 'push');
            app.RunGSEAPrerankedButton.ButtonPushedFcn = createCallbackFcn(app, @RunGSEAPrerankedButtonPushed, true);
            app.RunGSEAPrerankedButton.Text = 'Run GSEA (Preranked)...';
            app.AboutTab = uitab(app.TabGroup2);
            app.AboutTab.Title = 'About';
            app.ContributorsTextAreaLabel = uilabel(app.AboutTab);
            app.ContributorsTextAreaLabel.Text = 'Contributors:';
            app.ContributorsTextArea = uitextarea(app.AboutTab);
            app.ContributorsTextArea.Value = {'Jonas Lundgren <splinefit@gmail.com> --- SPLINEFIT.m'; 'Michal Marczyk <Michal.Marczyk@polsl.pl> --- GSEA in Matlab'; 'Jean-Philippe Fortin <fortin946@gmail.com> --- ComBat in Matlab'};
            app.AuthorLabel = uilabel(app.AboutTab);
            app.AuthorLabel.Text = 'Author:';
            app.AuthorTextArea = uitextarea(app.AboutTab);
            app.AuthorTextArea.Value = {'James J. Cai, Ph.D.'; 'Department of Veterinary Integrative Biosciences'; 'Department of Electrical & Computer Engineering (affiliated)'; 'Texas A&M University'; 'College Station, TX 77843-4458'; 'Tel (979) 458-5482 | Fax (979) 847-8981'; 'jcai@tamu.edu'; 'http://people.tamu.edu/~jcai/'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = scDETool5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.scDEToolv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.scDEToolv10UIFigure)
        end
    end
end