classdef scGEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SingleCellGeneExpressionAnalyzerscGEAUIFigure  matlab.ui.Figure
        Files                           matlab.ui.container.Menu
        OpenFile                        matlab.ui.container.Menu
        AssignDataintoWorkspaceMenu     matlab.ui.container.Menu
        Exit                            matlab.ui.container.Menu
        MatrixMenu                      matlab.ui.container.Menu
        ViewDataMenu                    matlab.ui.container.Menu
        FilterCellsMenu                 matlab.ui.container.Menu
        FilterGenesMenu                 matlab.ui.container.Menu
        LibrarySizeNormalizationMenu    matlab.ui.container.Menu
        Log10x1TransformationMenu       matlab.ui.container.Menu
        DESeqNormalizationMenu          matlab.ui.container.Menu
        MAGICvanDijk2018ImputationMenu  matlab.ui.container.Menu
        FeaturesMenu                    matlab.ui.container.Menu
        ViewTableMenu                   matlab.ui.container.Menu
        HighlyVariableGenesHVGsDetectionMenu  matlab.ui.container.Menu
        HVGsBrenneckeetal2013Menu       matlab.ui.container.Menu
        scVEGsChenetal2016Menu          matlab.ui.container.Menu
        scLVMBuettneretal2015Menu       matlab.ui.container.Menu
        M3DropAndrewsHemberg2018Menu    matlab.ui.container.Menu
        FS3DCaietal2019Menu             matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        MeanCVScatterMenu               matlab.ui.container.Menu
        MeanlogVarlogScatterMenu        matlab.ui.container.Menu
        MeanDropoutScatterMenu          matlab.ui.container.Menu
        MeanDropoutCV3DScatterMenu      matlab.ui.container.Menu
        PCAMenu                         matlab.ui.container.Menu
        tSNEMenu                        matlab.ui.container.Menu
        DiffusionMapDMMenu              matlab.ui.container.Menu
        FeaturedGenes3DStemMenu         matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        cNMFKotliar2019Menu             matlab.ui.container.Menu
        GeneSetEnrichmentAnalysisGSEAMenu  matlab.ui.container.Menu
        SingCellGeneExpressionAnalyzerscGEAv10Label  matlab.ui.control.Label
    end

    
    properties (Access = public)
        X           % single cell expression matrix [genes-by-cells]
        genelist
        T=table();           % Table of result
    end   


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure,'center');
            [app.X,app.genelist]=sc_readfile('example_data/GSM3044891_GeneExp.UMIs.10X1.txt');
            app.Log10x1TransformationMenu.Checked=false;
            app.Log10x1TransformationMenu.Visible=true;
            app.T=sc_3dfeadev(app.X,app.genelist);
            %[d,genes,stat]=sc_3dfeadev(app.X,app.genelist);
            %app.T=table(genes,stat.lgu,stat.dropr,stat.lgcv,d,...
            %    'variablenames',{'Genes','Log10_Mean','Dropout_Rate','Log10_CV','Deviation_3D'});
            %app.T=sortrows(app.T,5,'descend');
        end

        % Menu selected function: OpenFile
        function OpenFileMenuSelected(app, event)
            % https://www.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            commandwindow;
            [x,g]=sc_readfile;
            app.X=x;
            app.genelist=g;
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Menu selected function: MAGICvanDijk2018ImputationMenu
        function MAGICvanDijk2018ImputationMenuSelected2(app, event)
            f = waitbar(0,'Please wait...'); pause(.5)
            waitbar(.33,f,'Processing your data'); pause(.5);
            [app.X]=sc_impute_magic(app.X);
            waitbar(1,f,'Finishing'); pause(1); close(f);
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Menu selected function: FilterGenesMenu
        function FilterGenesMenuSelected(app, event)
            listv={'1','10','20','50'};
            [indx,tf] = listdlg('ListString',listv,...
                'Name','Cutoff',...
                'PromptString','Min. # of non-zeros (per gene):',...
                'SelectionMode','single',...
                'ListSize',[200 80]);
            if tf
                [app.X,app.genelist]=sc_filtergenes(app.X,app.genelist,...
                           str2double(listv{indx}));
                app.FilterGenesMenu.Checked=true;
                dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            end
        end

        % Menu selected function: FilterCellsMenu
        function FilterCellsMenuSelected(app, event)
            f=uifigure('Name','Filter Cells','Position',[100 100 300 250]);
            %lb = uilistbox(f,'Items',["10","20","50","100"],...
            %    'ItemsData',[10 20 50 100]); 
            dd = uidropdown(f);
            
%             listv={'10','20','50','100'};
%             [indx,tf] = listdlg('ListString',listv,...
%                 'Name','Cutoff',...
%                 'PromptString','Min. # of non-zeros (per cell):',...
%                 'SelectionMode','single',...
%                 'ListSize',[200 80]);
%             if tf                
%                 % commandwindow;
%                 [app.X]=sc_filtercells(app.X,str2double(listv{indx}),false);
%                 dbk=dbstack;
%                 helpdlg([dbk(1).name '......done.']);
%                 app.FilterCellsMenu.Checked=true;
%             end
        end

        % Menu selected function: ViewDataMenu
        function ViewDataMenuSelected(app, event)
            if ~isempty(app.X) && ~isempty(app.genelist)                
                t=table();
                t.genes=string(app.genelist(1:50));
                t=[t,array2table(app.X(1:50,1:20))];
                f=uifigure('Name','View Expression Matrix');
                uitable(f,'Position',[10 10 540 400],'Data',t);
            else
                warndlg('Empty matrix.');
            end
        end

        % Menu selected function: Exit
        function ExitMenuSelected(app, event)
            selection = questdlg('Exit scGEA?','Confirmation','Yes','No','Yes');
            switch selection
                case 'Yes'
                      delete(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure);               
                case 'No'
                    return
            end   
        end

        % Menu selected function: ViewTableMenu
        function ViewTableMenuSelected(app, event)
            f=uifigure('Name','Single-cell 3D Feature Deviation');
            app.T=sc_3dfeadev(app.X,app.genelist);
            %[d,genes,stat]=sc_3dfeadev(app.X,app.genelist);
            %app.T=table(genes,stat.lgu,stat.dropr,stat.lgcv,d,...
            %    'variablenames',{'Genes','Log10_Mean','Dropout_Rate','Log10_CV','Deviation_3D'});
            %app.T=sortrows(app.T,5,'descend');
            uitable(f,'Position',[10 10 540 370],'Data',app.T);
            btn=uibutton(f,'Text','Save...','Position',[10 390 100 22],...
                'ButtonPushedFcn', @(btn,event) saveButtonPushed);
            
            function saveButtonPushed
                [file,path] = uiputfile({'*.csv','CSV Files (*.csv)';'*.*','All Files (*.*)'});
                if isequal(file,0) || isequal(path,0)
                   disp('User clicked Cancel.')
                else
                   fn=fullfile(path,file);
                   disp(['User selected ',fn,...
                         ' and then clicked Save.'])
                   writetable(app.T,fn);
                end
            end
        end

        % Menu selected function: tSNEMenu
        function tSNEMenuSelected(app, event)
            f = waitbar(0,'Please wait...'); pause(.5);
            waitbar(.33,f,'Processing your data'); pause(.5);
            s=tsne(app.X');
            waitbar(1,f,'Finishing'); pause(1); close(f);
            fig = uifigure;
            ax = uiaxes(fig,'Position',[10 10 520 360]);     
            scatter(ax,s(:,1),s(:,2));
            xlabel(ax,'tSNP\_1');
            ylabel(ax,'tSNP\_2');
            title(ax,'tSNE (Perplexity = 30)');
        end

        % Menu selected function: PCAMenu
        function PCAMenuSelected(app, event)
            f = waitbar(0,'Please wait...'); pause(.5)
            waitbar(.33,f,'Processing your data'); pause(.5);
            [~,s]=pca(app.X');
            waitbar(1,f,'Finishing'); pause(1); close(f);
            figure;
            scatter(s(:,1),s(:,2));
            box on
            xlabel('PC\_1');
            ylabel('PC\_2');
        end

        % Menu selected function: MeanDropoutCV3DScatterMenu
        function MeanDropoutCV3DScatterMenuSelected(app, event)
            figure;
            scatter3_mean_cv_dropr(app.X);
        end

        % Menu selected function: MeanDropoutScatterMenu
        function MeanDropoutScatterMenuSelected(app, event)
            figure;
            subplot(2,2,1)
            scatter_mean_vs_dropr(app.X,app.genelist,1);
            subplot(2,2,2)
            scatter_mean_vs_dropr(app.X,app.genelist,3);
        end

        % Menu selected function: MeanlogVarlogScatterMenu
        function MeanlogVarlogScatterMenuSelected(app, event)
            figure;
            scatter_meanlg_vs_varlg(app.X,app.genelist);
        end

        % Menu selected function: MeanCVScatterMenu
        function MeanCVScatterMenuSelected(app, event)
            figure;
            scatter_mean_vs_cv(app.X,app.genelist);
        end

        % Menu selected function: DESeqNormalizationMenu
        function DESeqNormalizationMenuSelected(app, event)
            [app.X]=normalize_deseq(app.X);
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
            app.DESeqNormalizationMenu.Checked=true;
        end

        % Menu selected function: LibrarySizeNormalizationMenu
        function LibrarySizeNormalizationMenuSelected(app, event)
            [app.X]=normalize_libsize(app.X);
            app.LibrarySizeNormalizationMenu.Checked=true;
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Menu selected function: Log10x1TransformationMenu
        function Log10x1TransformationMenuSelected(app, event)
            x=log10(app.X+1);
            x(isinf(x))=nan;
            app.X=x;
            app.Log10x1TransformationMenu.Checked=true;
            dbk=dbstack; helpdlg([dbk(1).name '......done.']);
        end

        % Menu selected function: GeneSetEnrichmentAnalysisGSEAMenu
        function GeneSetEnrichmentAnalysisGSEAMenuSelected(app, event)
                      
            f=uifigure('Name','GSEA Result');
            itemv={'Select rank variable', app.T.Properties.VariableNames{2:end}};
            dd=uidropdown(f,'Items',itemv,'Position',[10 390 100 22]);
            t=uitable(f,'Position',[10 10 540 370]);
            btn=uibutton(f,'Text','Run...','Position',[110 390 100 22],...
                'ButtonPushedFcn', @(btn,event) aaa(t,dd));            
            
            function aaa(t,dd)
                fw = waitbar(0,'Please wait...'); pause(.5)                
                [~,idx]=ismember(dd.Value,app.T.Properties.VariableNames);
                grank=app.T(:,idx);                
                glist=table2array(app.T(:,1));
                waitbar(.33,fw,'Processing your data'); pause(.5);
                [Tp]=run_gsea(grank,glist);
                t.Data=Tp;
                waitbar(1,fw,'Finishing'); pause(1); close(fw);
            end
        end

        % Menu selected function: AssignDataintoWorkspaceMenu
        function AssignDataintoWorkspaceMenuSelected(app, event)
            
            labels = {'Save app.X to variable named:' ...
                      'Save app.GENELIST to variable named:'...
                      'Save app.T to variable named:'}; 
                vars = {'X','genelist','T'}; 
                values = {app.X,app.genelist,app.T};
                export2wsdlg(labels,vars,values);
            
%             if ~isempty(app.X)
%     	       assignin('base','X',app.X);
%             else
%                 warndlg('Expression matrix X is empty.','Assign data into workspace')
%                 return;
%             end
%             if ~isempty(app.genelist)
%     	       assignin('base','genelist',app.genelist);
%             else
%                 warndlg('Varible GENELIST is empty.','Assign data into workspace')
%                 return;
%             end
%             if ~isempty(app.T)
%     	       assignin('base','T',app.T);
%             else
%                 warndlg('Result table T is empty.','Assign data into workspace')
%                 return;
%             end
%             helpdlg('X, GENELIST and T assigned.','Assign data into workspace')           
        end

        % Menu selected function: FeaturedGenes3DStemMenu
        function FeaturedGenes3DStemMenuSelected(app, event)
            
            answer = questdlg('Top 50 genes will be selected based on the value of last column of Feature table...', ...
                'Select Action', ...
               	'Yes','Cancel','Yes');
                switch answer
                    case 'Yes'
                        
                    case 'Cancel'
                        return;
                end            
            bigX=[]; bigY=[]; bigZ=[];
            app.T=sortrows(app.T,size(app.T,2),'descend');
            [~,idx]=ismember(app.T.genes,app.genelist);
            for k=1:50
                i=idx(k);
                z=app.X(i,:);
                x=k*ones(size(z));
                y=1:length(z);
                bigX=[bigX;x];
                bigY=[bigY;y];
                bigZ=[bigZ;z];
            end
            figure;
            stem3(bigX,bigY,bigZ,'marker','none');
            view(60,35)
            xlabel('Genes')
            ylabel('Cells')
            zlabel('Expression (UMIs)')
            set(gca,'XTick',1:50)
            set(gca,'XTickLabel',app.T.genes(1:50))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SingleCellGeneExpressionAnalyzerscGEAUIFigure = uifigure;
            app.SingleCellGeneExpressionAnalyzerscGEAUIFigure.Name = 'Single Cell Gene Expression Analyzer (scGEA)';
            app.Files = uimenu(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure);
            app.Files.Text = '&File';
            app.OpenFile = uimenu(app.Files);
            app.OpenFile.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFile.Accelerator = 'o';
            app.OpenFile.Text = 'Open...';
            app.AssignDataintoWorkspaceMenu = uimenu(app.Files);
            app.AssignDataintoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @AssignDataintoWorkspaceMenuSelected, true);
            app.AssignDataintoWorkspaceMenu.Separator = 'on';
            app.AssignDataintoWorkspaceMenu.Text = 'Assign Data into Workspace...';
            app.Exit = uimenu(app.Files);
            app.Exit.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.Exit.Separator = 'on';
            app.Exit.Text = 'Exit';
            app.MatrixMenu = uimenu(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure);
            app.MatrixMenu.Text = 'Matrix';
            app.ViewDataMenu = uimenu(app.MatrixMenu);
            app.ViewDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewDataMenuSelected, true);
            app.ViewDataMenu.Text = 'View Data...';
            app.FilterCellsMenu = uimenu(app.MatrixMenu);
            app.FilterCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterCellsMenuSelected, true);
            app.FilterCellsMenu.Separator = 'on';
            app.FilterCellsMenu.Text = 'Filter Cells...';
            app.FilterGenesMenu = uimenu(app.MatrixMenu);
            app.FilterGenesMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterGenesMenuSelected, true);
            app.FilterGenesMenu.Text = 'Filter Genes...';
            app.LibrarySizeNormalizationMenu = uimenu(app.MatrixMenu);
            app.LibrarySizeNormalizationMenu.MenuSelectedFcn = createCallbackFcn(app, @LibrarySizeNormalizationMenuSelected, true);
            app.LibrarySizeNormalizationMenu.Separator = 'on';
            app.LibrarySizeNormalizationMenu.Text = 'Library Size Normalization';
            app.Log10x1TransformationMenu = uimenu(app.MatrixMenu);
            app.Log10x1TransformationMenu.MenuSelectedFcn = createCallbackFcn(app, @Log10x1TransformationMenuSelected, true);
            app.Log10x1TransformationMenu.Text = 'Log10(x+1) Transformation';
            app.DESeqNormalizationMenu = uimenu(app.MatrixMenu);
            app.DESeqNormalizationMenu.MenuSelectedFcn = createCallbackFcn(app, @DESeqNormalizationMenuSelected, true);
            app.DESeqNormalizationMenu.Text = 'DESeq Normalization';
            app.MAGICvanDijk2018ImputationMenu = uimenu(app.MatrixMenu);
            app.MAGICvanDijk2018ImputationMenu.MenuSelectedFcn = createCallbackFcn(app, @MAGICvanDijk2018ImputationMenuSelected2, true);
            app.MAGICvanDijk2018ImputationMenu.Separator = 'on';
            app.MAGICvanDijk2018ImputationMenu.Text = 'MAGIC (van Dijk 2018) Imputation...';
            app.FeaturesMenu = uimenu(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure);
            app.FeaturesMenu.Text = 'Features';
            app.ViewTableMenu = uimenu(app.FeaturesMenu);
            app.ViewTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewTableMenuSelected, true);
            app.ViewTableMenu.Text = 'View Table...';
            app.HighlyVariableGenesHVGsDetectionMenu = uimenu(app.FeaturesMenu);
            app.HighlyVariableGenesHVGsDetectionMenu.Separator = 'on';
            app.HighlyVariableGenesHVGsDetectionMenu.Text = 'Highly Variable Genes (HVGs) Detection';
            app.HVGsBrenneckeetal2013Menu = uimenu(app.FeaturesMenu);
            app.HVGsBrenneckeetal2013Menu.Text = '   HVGs (Brennecke, et al 2013)...';
            app.scVEGsChenetal2016Menu = uimenu(app.FeaturesMenu);
            app.scVEGsChenetal2016Menu.Text = '   scVEGs (Chen et al. 2016)...';
            app.scLVMBuettneretal2015Menu = uimenu(app.FeaturesMenu);
            app.scLVMBuettneretal2015Menu.Text = '   scLVM (Buettner et al. 2015)...';
            app.M3DropAndrewsHemberg2018Menu = uimenu(app.FeaturesMenu);
            app.M3DropAndrewsHemberg2018Menu.Text = '   M3Drop (Andrews && Hemberg 2018)...';
            app.FS3DCaietal2019Menu = uimenu(app.FeaturesMenu);
            app.FS3DCaietal2019Menu.Separator = 'on';
            app.FS3DCaietal2019Menu.Text = '   FS3D (Cai et al. 2019)...';
            app.PlotsMenu = uimenu(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.MeanCVScatterMenu = uimenu(app.PlotsMenu);
            app.MeanCVScatterMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanCVScatterMenuSelected, true);
            app.MeanCVScatterMenu.Separator = 'on';
            app.MeanCVScatterMenu.Text = 'Mean-CV Scatter...';
            app.MeanlogVarlogScatterMenu = uimenu(app.PlotsMenu);
            app.MeanlogVarlogScatterMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanlogVarlogScatterMenuSelected, true);
            app.MeanlogVarlogScatterMenu.Text = 'Mean(log)-Var(log) Scatter...';
            app.MeanDropoutScatterMenu = uimenu(app.PlotsMenu);
            app.MeanDropoutScatterMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanDropoutScatterMenuSelected, true);
            app.MeanDropoutScatterMenu.Text = 'Mean-Dropout Scatter...';
            app.MeanDropoutCV3DScatterMenu = uimenu(app.PlotsMenu);
            app.MeanDropoutCV3DScatterMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanDropoutCV3DScatterMenuSelected, true);
            app.MeanDropoutCV3DScatterMenu.Separator = 'on';
            app.MeanDropoutCV3DScatterMenu.Text = 'Mean-Dropout-CV 3D Scatter...';
            app.PCAMenu = uimenu(app.PlotsMenu);
            app.PCAMenu.MenuSelectedFcn = createCallbackFcn(app, @PCAMenuSelected, true);
            app.PCAMenu.Separator = 'on';
            app.PCAMenu.Text = 'PCA...';
            app.tSNEMenu = uimenu(app.PlotsMenu);
            app.tSNEMenu.MenuSelectedFcn = createCallbackFcn(app, @tSNEMenuSelected, true);
            app.tSNEMenu.Text = 't-SNE...';
            app.DiffusionMapDMMenu = uimenu(app.PlotsMenu);
            app.DiffusionMapDMMenu.Text = 'Diffusion Map (DM)...';
            app.FeaturedGenes3DStemMenu = uimenu(app.PlotsMenu);
            app.FeaturedGenes3DStemMenu.MenuSelectedFcn = createCallbackFcn(app, @FeaturedGenes3DStemMenuSelected, true);
            app.FeaturedGenes3DStemMenu.Separator = 'on';
            app.FeaturedGenes3DStemMenu.Text = 'Featured Genes 3D Stem...';
            app.ToolsMenu = uimenu(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.cNMFKotliar2019Menu = uimenu(app.ToolsMenu);
            app.cNMFKotliar2019Menu.Text = 'cNMF (Kotliar 2019)...';
            app.GeneSetEnrichmentAnalysisGSEAMenu = uimenu(app.ToolsMenu);
            app.GeneSetEnrichmentAnalysisGSEAMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneSetEnrichmentAnalysisGSEAMenuSelected, true);
            app.GeneSetEnrichmentAnalysisGSEAMenu.Separator = 'on';
            app.GeneSetEnrichmentAnalysisGSEAMenu.Text = 'Gene Set Enrichment Analysis (GSEA)...';
            app.SingCellGeneExpressionAnalyzerscGEAv10Label = uilabel(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure);
            app.SingCellGeneExpressionAnalyzerscGEAv10Label.Text = {'Sing Cell Gene Expression Analyzer (scGEA) v1.0'; 'https://github.com/cailab-tamu/GEAToolbox'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = scGEA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SingleCellGeneExpressionAnalyzerscGEAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SingleCellGeneExpressionAnalyzerscGEAUIFigure)
        end
    end
end